classdef Filter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        VerticalfilteringPanel          matlab.ui.container.Panel
        stop1MHzEditFieldLabel          matlab.ui.control.Label
        stop1MHzEditField               matlab.ui.control.NumericEditField
        pass1MHzLabel                   matlab.ui.control.Label
        pass1MHzEditField               matlab.ui.control.NumericEditField
        YesButton                       matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        FrequencydomainfilteringButton  matlab.ui.control.RadioButton
        FIRButton                       matlab.ui.control.RadioButton
        IIRButton                       matlab.ui.control.RadioButton
        pass2MHzEditFieldLabel          matlab.ui.control.Label
        pass2MHzEditField               matlab.ui.control.NumericEditField
        stop2MHzEditFieldLabel          matlab.ui.control.Label
        stop2MHzEditField               matlab.ui.control.NumericEditField
        HorizontalfilteringPanel        matlab.ui.container.Panel
        stop11EditFieldLabel            matlab.ui.control.Label
        stop11EditField                 matlab.ui.control.NumericEditField
        pass11EditFieldLabel            matlab.ui.control.Label
        pass11EditField                 matlab.ui.control.NumericEditField
        YesButton_2                     matlab.ui.control.Button
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        FIRButton_2                     matlab.ui.control.RadioButton
        IIRButton_2                     matlab.ui.control.RadioButton
        pass21EditFieldLabel            matlab.ui.control.Label
        pass21EditField                 matlab.ui.control.NumericEditField
        stop21EditFieldLabel            matlab.ui.control.Label
        stop21EditField                 matlab.ui.control.NumericEditField
        PredictivedeconvolutionPanel    matlab.ui.container.Panel
        LengthnsEditFieldLabel          matlab.ui.control.Label
        LengthnsEditField               matlab.ui.control.NumericEditField
        YesButton_3                     matlab.ui.control.Button
        LagnsEditFieldLabel             matlab.ui.control.Label
        LagnsEditField                  matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        callingapp % 保存主app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.callingapp = mainapp; %保存主app
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            if app.IIRButton.Value || app.FIRButton.Value
                app.stop1MHzEditField.Enable = 'on';
                app.stop2MHzEditField.Enable = 'on';
            elseif app.FrequencydomainfilteringButton.Value
                app.stop1MHzEditField.Enable = 'off';
                app.stop2MHzEditField.Enable = 'off';
                app.stop1MHzEditField.Value = 0;
                app.stop2MHzEditField.Value = 0;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingapp.FilterMenu.Enable = 'on';%开启主app一维滤波模块
            delete(app)
            
        end

        % Button pushed function: YesButton
        function YesButtonPushed(app, event)
            if app.FrequencydomainfilteringButton.Value
                Processing_Plus(app.callingapp,1,app.VerticalfilteringPanel.Title,app.FrequencydomainfilteringButton.Text,app.stop1MHzEditField.Value,...
                    app.pass1MHzEditField.Value,app.pass2MHzEditField.Value,app.stop2MHzEditField.Value,0,0,0);
            elseif app.FIRButton.Value
                Processing_Plus(app.callingapp,1,app.VerticalfilteringPanel.Title,app.FIRButton.Text,app.stop1MHzEditField.Value,...
                    app.pass1MHzEditField.Value,app.pass2MHzEditField.Value,app.stop2MHzEditField.Value,0,0,0);
            elseif app.IIRButton.Value
                Processing_Plus(app.callingapp,1,app.VerticalfilteringPanel.Title,app.IIRButton.Text,app.stop1MHzEditField.Value,...
                    app.pass1MHzEditField.Value,app.pass2MHzEditField.Value,app.stop2MHzEditField.Value,0,0,0);
            end
        end

        % Button pushed function: YesButton_2
        function YesButton_2Pushed(app, event)
            if app.FIRButton_2.Value
                Processing_Plus(app.callingapp,1,app.HorizontalfilteringPanel.Title,app.FIRButton_2.Text,app.stop11EditField.Value,...
                    app.pass11EditField.Value,app.pass21EditField.Value,app.stop21EditField.Value,0,0,0);
            elseif app.IIRButton_2.Value
                Processing_Plus(app.callingapp,1,app.HorizontalfilteringPanel.Title,app.IIRButton_2.Text,app.stop11EditField.Value,...
                    app.pass11EditField.Value,app.pass21EditField.Value,app.stop21EditField.Value,0,0,0);
            end
        end

        % Button pushed function: YesButton_3
        function YesButton_3Pushed(app, event)
            Processing_Plus(app.callingapp,1,app.PredictivedeconvolutionPanel.Title,app.LengthnsEditField.Value,app.LagnsEditField.Value,0,0,0,0,0,0);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'face.jpg';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.VerticalfilteringPanel = uipanel(app.UIFigure);
            app.VerticalfilteringPanel.Tooltip = {'垂向频率域滤波，参数必须从小到大，无重复'};
            app.VerticalfilteringPanel.Title = 'Vertical filtering';
            app.stop1MHzEditFieldLabel = uilabel(app.VerticalfilteringPanel);
            app.stop1MHzEditFieldLabel.Enable = 'off';
            app.stop1MHzEditFieldLabel.Text = 'stop1(MHz)';
            app.stop1MHzEditField = uieditfield(app.VerticalfilteringPanel, 'numeric');
            app.stop1MHzEditField.Enable = 'off';
            app.stop1MHzEditField.Tooltip = {'阻带频率，降低40dB.'};
            app.stop1MHzEditField.Value = 150;
            app.pass1MHzLabel = uilabel(app.VerticalfilteringPanel);
            app.pass1MHzLabel.Text = 'pass1(MHz)';
            app.pass1MHzEditField = uieditfield(app.VerticalfilteringPanel, 'numeric');
            app.pass1MHzEditField.Tooltip = {'通带频率（下限）.'};
            app.pass1MHzEditField.Value = 300;
            app.YesButton = uibutton(app.VerticalfilteringPanel, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton.Text = 'Yes';
            app.ButtonGroup = uibuttongroup(app.VerticalfilteringPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.FrequencydomainfilteringButton = uiradiobutton(app.ButtonGroup);
            app.FrequencydomainfilteringButton.Tooltip = {'频率域矩形窗利用FFT和IFFT滤波。信号首尾可能出现失真。'};
            app.FrequencydomainfilteringButton.Text = 'Frequency domain filtering';
            app.FrequencydomainfilteringButton.Value = true;
            app.FIRButton = uiradiobutton(app.ButtonGroup);
            app.FIRButton.Tooltip = {'FIR等波纹滤波器。推荐方法'};
            app.FIRButton.Text = 'FIR';
            app.IIRButton = uiradiobutton(app.ButtonGroup);
            app.IIRButton.Tooltip = {'IIR滤波器。切比雪夫2型'};
            app.IIRButton.Text = 'IIR';
            app.pass2MHzEditFieldLabel = uilabel(app.VerticalfilteringPanel);
            app.pass2MHzEditFieldLabel.Text = 'pass2(MHz)';
            app.pass2MHzEditField = uieditfield(app.VerticalfilteringPanel, 'numeric');
            app.pass2MHzEditField.Tooltip = {'通带频率（上限），不能超过fs/2'};
            app.pass2MHzEditField.Value = 600;
            app.stop2MHzEditFieldLabel = uilabel(app.VerticalfilteringPanel);
            app.stop2MHzEditFieldLabel.Enable = 'off';
            app.stop2MHzEditFieldLabel.Text = 'stop2(MHz)';
            app.stop2MHzEditField = uieditfield(app.VerticalfilteringPanel, 'numeric');
            app.stop2MHzEditField.Enable = 'off';
            app.stop2MHzEditField.Tooltip = {'阻带频率，降低40dB.不能超过fs/2'};
            app.stop2MHzEditField.Value = 850;
            app.HorizontalfilteringPanel = uipanel(app.UIFigure);
            app.HorizontalfilteringPanel.Tooltip = {'横向波数域滤波。垂向频率域滤波，参数必须从小到大，无重复'};
            app.HorizontalfilteringPanel.Title = 'Horizontal filtering';
            app.stop11EditFieldLabel = uilabel(app.HorizontalfilteringPanel);
            app.stop11EditFieldLabel.Text = 'stop1(1)';
            app.stop11EditField = uieditfield(app.HorizontalfilteringPanel, 'numeric');
            app.stop11EditField.Tooltip = {'阻带频率，降低40dB.'};
            app.stop11EditField.Value = 3;
            app.pass11EditFieldLabel = uilabel(app.HorizontalfilteringPanel);
            app.pass11EditFieldLabel.Text = 'pass1(1)';
            app.pass11EditField = uieditfield(app.HorizontalfilteringPanel, 'numeric');
            app.pass11EditField.Tooltip = {'通带频率（下限）.'};
            app.pass11EditField.Value = 5;
            app.YesButton_2 = uibutton(app.HorizontalfilteringPanel, 'push');
            app.YesButton_2.ButtonPushedFcn = createCallbackFcn(app, @YesButton_2Pushed, true);
            app.YesButton_2.Text = 'Yes';
            app.ButtonGroup_2 = uibuttongroup(app.HorizontalfilteringPanel);
            app.FIRButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.FIRButton_2.Tooltip = {'FIR等波纹滤波器。推荐方法'};
            app.FIRButton_2.Text = 'FIR';
            app.FIRButton_2.Value = true;
            app.IIRButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.IIRButton_2.Tooltip = {'IIR滤波器。切比雪夫2型'};
            app.IIRButton_2.Text = 'IIR';
            app.pass21EditFieldLabel = uilabel(app.HorizontalfilteringPanel);
            app.pass21EditFieldLabel.Text = 'pass2(1)';
            app.pass21EditField = uieditfield(app.HorizontalfilteringPanel, 'numeric');
            app.pass21EditField.Tooltip = {'通带频率（上限）.'};
            app.pass21EditField.Value = 10;
            app.stop21EditFieldLabel = uilabel(app.HorizontalfilteringPanel);
            app.stop21EditFieldLabel.Text = 'stop2(1)';
            app.stop21EditField = uieditfield(app.HorizontalfilteringPanel, 'numeric');
            app.stop21EditField.Tooltip = {'阻带频率，降低40dB.不能超过fs/2'};
            app.stop21EditField.Value = 15;
            app.PredictivedeconvolutionPanel = uipanel(app.UIFigure);
            app.PredictivedeconvolutionPanel.Tooltip = {'垂向频率域滤波'};
            app.PredictivedeconvolutionPanel.Title = 'Predictive deconvolution';
            app.LengthnsEditFieldLabel = uilabel(app.PredictivedeconvolutionPanel);
            app.LengthnsEditFieldLabel.Text = 'Length(ns)';
            app.LengthnsEditField = uieditfield(app.PredictivedeconvolutionPanel, 'numeric');
            app.LengthnsEditField.Tooltip = {'算子长度，小于时窗1/2。'};
            app.LengthnsEditField.Value = 40;
            app.YesButton_3 = uibutton(app.PredictivedeconvolutionPanel, 'push');
            app.YesButton_3.ButtonPushedFcn = createCallbackFcn(app, @YesButton_3Pushed, true);
            app.YesButton_3.Text = 'Yes';
            app.LagnsEditFieldLabel = uilabel(app.PredictivedeconvolutionPanel);
            app.LagnsEditFieldLabel.Text = 'Lag(ns)';
            app.LagnsEditField = uieditfield(app.PredictivedeconvolutionPanel, 'numeric');
            app.LagnsEditField.Tooltip = {'预测延时，小于算子长度，大于一次反射'};
            app.LagnsEditField.Value = 35;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Filter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end