classdef GPRlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GPRlabUIFigure                  matlab.ui.Figure
        R_DCMenu                        matlab.ui.container.Menu
        R_backgroundMenu                matlab.ui.container.Menu
        GainMenu                        matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
        MathoperationsMenu              matlab.ui.container.Menu
        FilterMenu                      matlab.ui.container.Menu
        TwoDfilterMenu                  matlab.ui.container.Menu
        HilbertAnalysisMenu             matlab.ui.container.Menu
        InstantaneousAmplitudeMenu      matlab.ui.container.Menu
        InstantaneousPhaseMenu          matlab.ui.container.Menu
        InstantaneousFrequencyMenu      matlab.ui.container.Menu
        MigrationMenu                   matlab.ui.container.Menu
        SarMenu                         matlab.ui.container.Menu
        WaveletMenu                     matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        DataTab                         matlab.ui.container.Tab
        LoadPanel                       matlab.ui.container.Panel
        LoadButton                      matlab.ui.control.Button
        DataimformationPanel            matlab.ui.container.Panel
        AntennaMHzEditFieldLabel        matlab.ui.control.Label
        AntennaMHzEditField             matlab.ui.control.NumericEditField
        RelativepermittivityEditFieldLabel  matlab.ui.control.Label
        RelativepermittivityEditField   matlab.ui.control.NumericEditField
        TimewindownsEditFieldLabel      matlab.ui.control.Label
        TimewindownsEditField           matlab.ui.control.NumericEditField
        SamplingintervalmLabel          matlab.ui.control.Label
        SamplingintervalmEditField      matlab.ui.control.NumericEditField
        StartmLabel                     matlab.ui.control.Label
        StartmEditField                 matlab.ui.control.NumericEditField
        EndmEditField                   matlab.ui.control.NumericEditField
        EndmLabel                       matlab.ui.control.Label
        TimezeronsEditField             matlab.ui.control.NumericEditField
        TimezeronsEditFieldLabel        matlab.ui.control.Label
        YesButton                       matlab.ui.control.Button
        RawdataPanel                    matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        DatasizePanel                   matlab.ui.container.Panel
        RowsEditFieldLabel              matlab.ui.control.Label
        RowsEditField                   matlab.ui.control.NumericEditField
        ColumnsEditFieldLabel           matlab.ui.control.Label
        ColumnsEditField                matlab.ui.control.NumericEditField
        MileagemEditFieldLabel          matlab.ui.control.Label
        MileagemEditField               matlab.ui.control.NumericEditField
        DepthmEditFieldLabel            matlab.ui.control.Label
        DepthmEditField                 matlab.ui.control.NumericEditField
        ImageprocessingTab              matlab.ui.container.Tab
        ProcessingPanel                 matlab.ui.container.Panel
        UITable2                        matlab.ui.control.Table
        upButton                        matlab.ui.control.Button
        downButton                      matlab.ui.control.Button
        deleteButton                    matlab.ui.control.Button
        LoadprocessingButton            matlab.ui.control.Button
        SaveprocessingButton            matlab.ui.control.Button
        YesButton_2                     matlab.ui.control.Button
        Reset_rawdataButton             matlab.ui.control.Button
        SavethicknessButton             matlab.ui.control.Button
        SaveDataButton                  matlab.ui.control.Button
        YesButton_3                     matlab.ui.control.Button
        ColorDropDownLabel              matlab.ui.control.Label
        ColorDropDown                   matlab.ui.control.DropDown
        TraceLengthEditFieldLabel       matlab.ui.control.Label
        TraceLengthEditField            matlab.ui.control.NumericEditField
        DistancemEditFieldLabel         matlab.ui.control.Label
        DistancemEditField              matlab.ui.control.NumericEditField
        Slider                          matlab.ui.control.Slider
        ContrastEditFieldLabel          matlab.ui.control.Label
        ContrastEditField               matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        WaveTab                         matlab.ui.container.Tab
        TracknumberEditFieldLabel       matlab.ui.control.Label
        TracknumberEditField            matlab.ui.control.NumericEditField
        ListBox                         matlab.ui.control.ListBox
        YesButton4                      matlab.ui.control.Button
        SaveplotdataButton              matlab.ui.control.Button
        SavespectrumdataButton          matlab.ui.control.Button
        BackgroundstartEditFieldLabel   matlab.ui.control.Label
        BackgroundstartEditField        matlab.ui.control.NumericEditField
        BackgroundendEditFieldLabel     matlab.ui.control.Label
        BackgroundendEditField          matlab.ui.control.NumericEditField
        DCButtonGroup                   matlab.ui.container.ButtonGroup
        NOButton                        matlab.ui.control.RadioButton
        YESButton                       matlab.ui.control.RadioButton
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        TimefrequencyanalysisTab        matlab.ui.container.Tab
        ListBox_2                       matlab.ui.control.ListBox
        TracknumberEditField_2Label     matlab.ui.control.Label
        TracknumberEditField_2          matlab.ui.control.NumericEditField
        BackgroundstartEditField_2Label  matlab.ui.control.Label
        BackgroundstartEditField_2      matlab.ui.control.NumericEditField
        BackgroundendEditField_2Label   matlab.ui.control.Label
        BackgroundendEditField_2        matlab.ui.control.NumericEditField
        ParameterPGSTPanel              matlab.ui.container.Panel
        Num_FpointsEditFieldLabel       matlab.ui.control.Label
        Num_FpointsEditField            matlab.ui.control.NumericEditField
        GeneralparameterEditFieldLabel  matlab.ui.control.Label
        GeneralparameterEditField       matlab.ui.control.NumericEditField
        YesButton4_2                    matlab.ui.control.Button
        SaveTFdataButton                matlab.ui.control.Button
        SaveSCdataButton                matlab.ui.control.Button
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
    end

  
    properties (Access = private)
        Raw_data                        % 原始数据array
        M                              double %原始数据行数
        N                              double %原始数据列数
        check_set_rawdata              logical%检查参数信息设置
        Antenna                        double %天线频率，MHz
        Permittivity                   double %相对介电常数
        Time_window                    double %时窗，ns
        Sam_interval                   double %采样间隔，m
        Start                          double%开始位置，m
        End                            double%结束位置 ，m
        Time_0                         double %时间0点，ns
        R_BGapp % Description,去背景参数app
        Gain_app%Description,增益app
        Math_app%Description,数学运算app
        Filter_app%Description,一维滤波app
        TwoDfilter_app%Description,二维滤波app
        Sar_app%Description,合成孔径聚焦成像app
        Wavelet_app%小波类算法app（去噪+滤波），app示例-1，
        bbox_app%Description,对话框app
        Se_Processing_rows              double%选择的处理流程的行
        Depth                           double %深度
        Mileage                         double%里程
        V_fs                            double%垂向采样频率
        H_fs                            double%水平向采样频率
        A  %%Plot中的原始数据
        B  %%Plot中的处理后数据
        C  %%Plot中的背景数据
        D  %%Plot中的原始数据与背景数据差值
        A_fftP1%原始数据单边频谱
        B_fftP1%处理后数据单边频谱
        C_fftP1%背景数据单边频谱
        D_fftP1%原始数据与背景数据单边频谱
        A_fftP11%原始数据单边频谱（不含直流）
        B_fftP11%处理后数据单边频谱（不含直流）
        C_fftP11%背景数据单边频谱（不含直流）
        D_fftP11%原始数据与背景数据单边频谱（不含直流）
        
        % 时频谱分析部分
        AA  %%Plot中的原始数据
        BB  %%Plot中的处理后数据
        CC  %%Plot中的背景数据
        DD  %%Plot中的原始数据与背景数据差值
        
        AA_GPST%  广义相移ST
        BB_GPST%
        CC_GPST%
        DD_GPST%

        AA_SSGPST% 同步压缩-广义相移ST
        BB_SSGPST%
        CC_SSGPST%
        DD_SSGPST%
        fs %时频图中的频率
        

    end
    
    properties (Access = public)
        Processing_data               double% 处理后的数据，表现在图中的数据
        Step                          double% 图像移动步长
        Plot_data                     % 波形图成像数据,这里不能设置为double形，因为实际上是一个table
        Spectrum_data                 %波形图对应的频谱数据
        TF_data                       %时频分析时的数据
        TF_GPST                       %PGST数据
        TF_SSGPST                     %SSPGST数据
        
        
    end
    methods (Access = public)
        %公有函数，增加表中的处理步骤
        function Processing_Plus(app,varargin)
         app.UITable2.Data = [app.UITable2.Data;varargin];  %将处理步骤更新到流程表中 
        end
    end
      methods (Access = private)
        %私有函数，对处理后的数据成图
        function Drawing(app)
          %%%%%%%%根据滑块位置不同画图
            s = (app.Slider.Value-1)*app.Sam_interval;
            e = (app.Slider.Value+app.Step-2)*app.Sam_interval;            
            app.UIAxes.XLim = [s,e];                  %更新横坐标的范围
            X1 = s:app.Sam_interval:e;                %横坐标表示里程
            Y1 = 0:app.Time_window/app.M:app.Time_window;           %纵坐标表示时间
            imagesc(app.UIAxes,X1,Y1,app.Processing_data(:,app.Slider.Value:(app.Slider.Value+app.Step-1)));
        end

    end  

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [filename,filepath] = uigetfile('*.*','Select file');
            if filename
                app.LoadButton.Enable = 'off';
                app.YesButton.Enable = 'off';
                h = waitbar(0.5, 'Loading data...'); %加载进度条
                [~, ~, ext] = fileparts(filename);
                ext = lower(ext);
                if strcmp(ext, '.dzt')
                    [app.Raw_data,HD] = readdzt_GPRlab(filepath,filename);
                    app.TimewindownsEditField.Value = HD.range;%采样间隔和时窗
%                     app.SamplingintervalmEditField.Value = HD.spm;
                elseif strcmp(ext, '.rd3')
                    [app.Raw_data,HD] = readrd3_GPRlab(filepath,filename);
                    app.TimewindownsEditField.Value = HD.range;%采样间隔和时窗
%                     app.SamplingintervalmEditField.Value = HD.spm;
                elseif strcmp(ext, '.dt1')
                    [app.Raw_data,HD] = readdt1_GPRlab(filepath,filename);
                    app.TimewindownsEditField.Value = HD.range;%采样间隔和时窗
%                     app.SamplingintervalmEditField.Value = HD.spm;
                else                
                    app.Raw_data = table2array(readtable([filepath,filename])) ;
                end
%                 Raw_data = app.Raw_data;
                [app.M,app.N] = size(app.Raw_data);
                app.UITable.Data = app.Raw_data;
                app.UITable.ColumnName = 1:app.N;
                app.UITable.RowName = 1:app.M;
                app.RowsEditField.Value = app.M;
                app.ColumnsEditField.Value = app.N;
                app.check_set_rawdata = 0;
                waitbar(0.9,h, 'Hold on!'); %加载完成
                if ishandle(h)
                    close(h);
                end
                app.bbox_app = bbox(app);                              %开启对话框
                pause(2);
                close(app.bbox_app.UIFigure);
                app.LoadButton.Enable = 'on';      %避免用户在读入数据的途中，继续读入数据。
                app.YesButton.Enable = 'on';
                assignin("base","Raw_data",app.Raw_data);         %数据存入matlab工作区
            end
        end

        % Button pushed function: YesButton
        function YesButtonPushed(app, event)
            %属性值的读入
            app.Antenna = app.AntennaMHzEditField.Value;
            app.Permittivity = app .RelativepermittivityEditField.Value;
            app.Time_window = app.TimewindownsEditField.Value;
            app.Sam_interval = app.SamplingintervalmEditField.Value;
            app.Start = app.StartmEditField.Value;
            app.Time_0 = app.TimezeronsEditField.Value;
            %显示原始数据大小

            if app.Sam_interval == 0
                app.End = app.EndmEditField.Value;
                app.MileagemEditField.Value = app.End-app.Start;
                app.Mileage = app.MileagemEditField.Value;
                if app.N              %这是为了防止没有输入数据造成的程序冲突，此时没有app.N
                    app.Sam_interval = app.MileagemEditField.Value/(app.N-1); %这种情况下，更新采样间隔的值
                    app.SamplingintervalmEditField.Value = app.Sam_interval;  %这种情况下，更新采样间隔的值
                end
%                 app.SamplingintervalmEditField.Enable = 'off';%只要输入了下面起始和终止位置，采样间隔就会锁定，不让编辑。
            else                
%                 app.EndmEditField.Enable = 'off';                         %这种情况下，锁定结束位置的设定。
                if app.N              %这是为了防止没有输入数据造成的程序冲突此时没有app.N
                    app.End = app.Start+(app.N-1)*app.Sam_interval;
                    app.MileagemEditField.Value = app.Sam_interval*(app.N-1);
                    app.Mileage = app.MileagemEditField.Value;
                    app.EndmEditField.Value = app.End;
                end
            end
            app.DepthmEditField.Value = (app.Time_window-app.Time_0)*0.15/sqrt(app.Permittivity);
            app.Depth = app.DepthmEditField.Value ;
            %%%%%%%%%计算垂向和水平向的采样频率%%%%%%%%%
            app.V_fs = (app.M-1)/app.Time_window*1000;
            app.H_fs = 1/app.Sam_interval;
            %%%%%%%%%绘制默认图像%%%%%%%
            app.Processing_data = app.UITable.Data;         %将原始数据赋值给处理数据
            colormap(app.UIAxes,gray);    %设定默认色彩模式，灰度（256）
            yyaxis(app.UIAxes,"right");
            app.UIAxes.YLim=[0,app.Time_window*0.15/sqrt(app.Permittivity)]; % 设置右y轴的界限，深度，y轴当参数表设定完毕就不变，当然参数表改变轴也会改变
            app.UIAxes.YLabel.String='Depth(m)'; % 给右y轴添加轴标签
            app.UIAxes.YDir= 'reverse';
            yyaxis(app.UIAxes,"left");
            app.UIAxes.YLabel.String='Time(ns)'; % 给左y轴添加轴标签
            app.UIAxes.YLim=[0,app.Time_window]; % 设置右y轴的界限，y轴当参数表设定完毕就不变，当然参数表改变轴也会改变
            app.UIAxes.XLim=[app.Start,app.End];
            X1 = app.Start:app.Sam_interval:app.End;                %横坐标表示里程
            Y1 = 0:app.Time_window/app.M:app.Time_window;           %纵坐标表示时间
            imagesc(app.UIAxes,X1,Y1,app.Processing_data);
            %%%让滑块滑到最大值%%%
            app.Slider.Limits = [1,app.N];
            app.Slider.Value = app.N;
            app.Step = 0;                                           %图像移动步长，默认值0
            app.Slider.Enable = 'off';
            %更新检查器
            app.check_set_rawdata = 1;                              %锁定住滑块
            delete(app.bbox_app);                                   %关闭前一个对话框
            app.bbox_app = bbox(app);                                    %开启对话框 
            pause(2);
            delete(app.bbox_app);
            app.YesButton4.Enable = 'on';
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
        delete(app.bbox_app);                                       %关闭对话框          
        end

        % Menu selected function: R_DCMenu
        function R_DCMenuSelected(app, event)
            R_DC = {1,'Removing DC',0,0,0,0,0,0,0,0};%注意，这里不能用字符串
            app.UITable2.Data = [app.UITable2.Data;R_DC];
        end

        % Menu selected function: R_backgroundMenu
        function R_backgroundMenuSelected(app, event)
            app.R_backgroundMenu.Enable = 'off';  %关闭去背景步骤，防止用户多次开启对话框
            app.R_BGapp = R_BG(app);        
        end

        % Close request function: GPRlabUIFigure
        function GPRlabUIFigureCloseRequest(app, event)
            delete(app.R_BGapp)%关闭去背景app
            delete(app.Gain_app)%关闭增益app
            delete(app.Math_app)%关闭数学运算app
            delete(app.Filter_app)%关闭一维滤波app
            delete(app.TwoDfilter_app)%关闭二维滤波app
            delete(app.Sar_app)%关闭合成孔径成像app
            delete(app.bbox_app)%关闭对话框app
            delete(app.Wavelet_app)%关闭对话框app，示例-n
            delete(app)
        end

        % Cell selection callback: UITable2
        function UITable2CellSelection(app, event)
            app.Se_Processing_rows = event.Indices(1);
        end

        % Button pushed function: upButton
        function upButtonPushed(app, event)
            %升序流程，超出索引则跳出函数，以免报错
            if app.Se_Processing_rows>1
                app.UITable2.Data([app.Se_Processing_rows,app.Se_Processing_rows-1],:) = app.UITable2.Data([app.Se_Processing_rows-1,app.Se_Processing_rows],:); 
                app.Se_Processing_rows = app.Se_Processing_rows-1;
            else
                return
            end
        end

        % Button pushed function: downButton
        function downButtonPushed(app, event)
            if app.Se_Processing_rows<size(app.UITable2.Data,1)
                app.UITable2.Data([app.Se_Processing_rows,app.Se_Processing_rows+1],:) = app.UITable2.Data([app.Se_Processing_rows+1,app.Se_Processing_rows],:);   %下降
                app.Se_Processing_rows = app.Se_Processing_rows+1;%跳到下一个处理流程
            else
                return
            end
        end

        % Button pushed function: deleteButton
        function deleteButtonPushed(app, event)
            if app.Se_Processing_rows >=1
            app.UITable2.Data(app.Se_Processing_rows,:) = [];%删除
            app.Se_Processing_rows = app.Se_Processing_rows-1;%跳到上一个处理流程
            end
        end

        % Button pushed function: SaveprocessingButton
        function SaveprocessingButtonPushed(app, event)
            [Filename,path] = uiputfile({'*.txt';'*.csv';'*.xlsx';'*.xls';'*.dat'},'Save Processing','Processing.xlsx');%保存处理步骤至excel
            if Filename
            writecell(app.UITable2.Data,[path,Filename]);
            end
        end

        % Button pushed function: LoadprocessingButton
        function LoadprocessingButtonPushed(app, event)
            [filename,filepath] = uigetfile('*.*','Select file');
            if filename
                app.UITable2.Data = readcell([filepath,filename]) ;%读取已有处理流程，注意要与保存的类型cell一致。否则无法保存读入的处理步骤
            end
%             app.YesButton_2.Enable = 'on';
        end

        % Value changed function: TraceLengthEditField
        function TraceLengthEditFieldValueChanged(app, event)
            value = app.TraceLengthEditField.Value;
            if value<app.N                                              %限制范围，不能大于数据的长度
            app.DistancemEditField.Value = (value-1)*app.Sam_interval;  %当输入图像道数，改变图像距离 
            else
                app.TraceLengthEditField.Value = 0;
                errordlg("Trace Length must be less than the number of data",'error！')
                return
            end
        end

        % Value changed function: DistancemEditField
        function DistancemEditFieldValueChanged(app, event)
            value = app.DistancemEditField.Value;
            if value<app.Mileage                                                   %限制范围，不能大于数据的长度
                app.TraceLengthEditField.Value = floor(value/app.Sam_interval)+1;  %当输入图像距离，改变图像道数
            else
                app.DistancemEditField.Value = 0;
                errordlg("Distance must be less than the Length  of data",'error！')
                return
            end
        end

        % Button pushed function: YesButton_2
        function YesButton_2Pushed(app, event)
            %%%%%%%%%%%%%%%%%%%%成像及算法应用(还是所有数据一起算比较好)%%%%%%%%%%%%%%%%%
            app.Processing_data = app.UITable.Data;         %这一步是为了在每次点击yes后，都能按照最新算法成图
            mm =size(app.UITable2.Data,1);
            h = waitbar(0, 'Processing...'); %加载进度条
            app.YesButton_2.Enable = 'off';
            for i=1:mm                                           %%%按从上到下的流程执行算法
                if app.UITable2.Data{i,1} ~= 0                   %%%判断是否激活了该步骤
                    check_method = app.UITable2.Data{i,2};
                    switch check_method                          %%%选择算法
                        case 'Removing DC'
                            app.Processing_data = removedc(app.Processing_data);
                        case 'Remove background'
                            app.Processing_data = removegb(app.Processing_data,app.UITable2.Data{i,3},app.UITable2.Data{i,4});
                        case 'Dynamic window background'
                            app.Processing_data = removedwgb(app.Processing_data,app.UITable2.Data{i,3},app.UITable2.Data{i,4},app.UITable2.Data{i,5});
                        case'SVD'
                            app.Processing_data = remove_svd(app.Processing_data,app.UITable2.Data{i,3});
                        case'Remove file'
                            app.Processing_data = removefile(app.Processing_data,app.UITable2.Data{i,3},app.UITable2.Data{i,4});
                        case 'Exponent gain'
                            app.Processing_data = Exponential_gain(app.Processing_data,app.UITable2.Data{i,3},app.UITable2.Data{i,4},app.UITable2.Data{i,5},app.UITable2.Data{i,6});
                        case 'Manual gain'
                            app.Processing_data = Manual_gain(app.Processing_data,app.UITable2.Data{i,3},app.UITable2.Data{i,4});
                        case 'Liner automatic gain'
                            app.Processing_data = Auto_gain(app.Processing_data,app.UITable2.Data{i,3},'Liner automatic gain',app.UITable2.Data{i,4});
                        case 'RMS automatic gain'
                            app.Processing_data = Auto_gain(app.Processing_data,app.UITable2.Data{i,3},'RMS');
                        case 'GRMS automatic gain'
                            app.Processing_data = Auto_gain(app.Processing_data,app.UITable2.Data{i,3},'GRMS');
                        case 'Plus'
                            app.Processing_data = app.Processing_data+app.UITable2.Data{i,3};
                        case 'Multiplication'
                            app.Processing_data = app.Processing_data*app.UITable2.Data{i,3};
                        case 'Absolute'
                            app.Processing_data = abs(app.Processing_data)*app.UITable2.Data{i,3};
                        case 'Square'
                            app.Processing_data = sqrt(abs(app.Processing_data))*app.UITable2.Data{i,3};
                        case 'Gradient'
                            app.Processing_data = Gradient(app.Processing_data,app.UITable2.Data{i,3},app.UITable2.Data{i,4},app.UITable2.Data{i,5});
                        case 'Vertical filtering'
                            app.Processing_data = V_filter(app.Processing_data,app.UITable2.Data{i,3},app.V_fs,app.UITable2.Data{i,4},app.UITable2.Data{i,5},...
                                app.UITable2.Data{i,6},app.UITable2.Data{i,7});
                        case 'Horizontal filtering'
                            app.Processing_data = H_filter(app.Processing_data,app.UITable2.Data{i,3},app.H_fs,app.UITable2.Data{i,4},app.UITable2.Data{i,5},...
                                app.UITable2.Data{i,6},app.UITable2.Data{i,7});
                        case 'Predictive deconvolution'
                            app.Processing_data = pre_dec(app.Processing_data,app.Time_window/(app.M-1),app.UITable2.Data{i,3},app.UITable2.Data{i,4});
                        case '2D filter'
                            app.Processing_data = TD_filter(app.Processing_data,app.UITable2.Data{i,3},app.UITable2.Data{i,4},app.UITable2.Data{i,5});
                        case 'f_k filter'
                            app.Processing_data = f_k(app.Processing_data,app.Sam_interval,app.UITable2.Data{i,3},app.UITable2.Data{i,4});                            
                        case 'EMD'
                            app.Processing_data =  filt_emd(app.Processing_data,app.UITable2.Data{i,3},app.UITable2.Data{i,4},app.UITable2.Data{i,5});
                        case 'VMD'
                            app.Processing_data =  filt_vmd(app.Processing_data,app.UITable2.Data{i,3},app.UITable2.Data{i,4},app.UITable2.Data{i,5},app.UITable2.Data{i,6});                            
                        case 'Amplitude'
                            app.Processing_data = Hl(app.Processing_data,'Amplitude');    
                        case 'Phase'
                            app.Processing_data = Hl(app.Processing_data,'Phase'); 
                        case 'Frequency'
                            app.Processing_data = Hl(app.Processing_data,'Frequency');
                        case 'SAR'
                            app.Processing_data = SAR_bp(app.Processing_data,app.Time_window,app.Sam_interval,app.UITable2.Data{i,4},app.UITable2.Data{i,3},...
                               app.UITable2.Data{i,5},app.UITable2.Data{i,6} );
                        case 'F-K migration'
                            app.Processing_data = fkmig(app.Processing_data,app.Time_window/(app.M-1),app.Sam_interval,app.UITable2.Data{i,3});
                        case 'OneD-dwt'
                            app.Processing_data = Wavelet_out(app.Processing_data,'OneDdwt',app.UITable2.Data{i,3},app.UITable2.Data{i,4},app.UITable2.Data{i,5},app.UITable2.Data{i,6},...
                                app.UITable2.Data{i,7},app.UITable2.Data{i,8},app.Time_window,app.M);
                            %示例3-1
                        case 'TwoD-dwt'
                            app.Processing_data = Wavelet_out(app.Processing_data,'TwoDdwt',app.UITable2.Data{i,3},app.UITable2.Data{i,4},app.UITable2.Data{i,5},app.UITable2.Data{i,6},...
                                app.UITable2.Data{i,7},app.UITable2.Data{i,8},app.Time_window,app.M);
                            %示例3-2
                        case 'OneD-cwt'
                            app.Processing_data = Wavelet_out(app.Processing_data,'OneDcwt',app.UITable2.Data{i,3},app.UITable2.Data{i,4},app.UITable2.Data{i,5},app.UITable2.Data{i,6},...
                                app.UITable2.Data{i,7},app.UITable2.Data{i,8},app.Time_window,app.M);
                            %示例3-3
                    end
                end
                waitbar(i/mm,h, 'Processing...'); %加载进度条
            end
            if ishandle(h)
                close(h);
            end
            app.YesButton_2.Enable = 'on';
            %%%%%%%%更新完处理后，更新图像区（重置的图像为整个数据成图）%%%%%%%%%
           app.UIAxes.XLim=[app.Start,app.End];
           X1 = app.Start:app.Sam_interval:app.End;                %横坐标表示里程
           Y1 = 0:app.Time_window/app.M:app.Time_window;           %纵坐标表示时间
           imagesc(app.UIAxes,X1,Y1,app.Processing_data);
           app.Slider.Value = app.N;
           app.Slider.Enable = 'off';
           %%%%%%%%%%运算完毕%%%%%%%%%%%%%
           assignin("base","Processing_data",app.Processing_data);         %数据存入matlab工作区
           msg = msgbox('Succeed！');
           pause(2);
           delete(msg);
           return;
        end

        % Key press function: GPRlabUIFigure
        function GPRlabUIFigureKeyPress(app, event)
            %%%%%%%键盘交互，利用键盘控制滑块的移动%%%%%
            key = event.Key;
            if strcmp(key,'leftarrow')                %控制左移
                if app.Slider.Value<app.Step
                    app.Slider.Value = 1;
                else
                    app.Slider.Value = app.Slider.Value-app.Step+1;
                end
                
            elseif strcmp(key,'rightarrow')           %控制右移
               
                if (app.Slider.Value+app.Step+1-1)>(app.N-app.Step+1)        %因为滑块画图函数Drawing（app）是以左侧移动步长，所以向右移动时需要判断下一步移动后是否会超出数据列数
                    app.Slider.Value = (app.N-app.Step+1);
                else
                    app.Slider.Value = app.Slider.Value+app.Step-1;
                end
                
            end
             Drawing(app);
        end

        % Button pushed function: YesButton_3
        function YesButton_3Pushed(app, event)
            %%%%%根据步长重置横坐标轴和滑块，并成图

            if app.TraceLengthEditField.Value    %判断书否输入每幅图的长度
                app.Step = floor(app.TraceLengthEditField.Value);
                app.UIAxes.XLim = [app.Start,(app.Step-1)*app.Sam_interval];%将图像移动到起始位置
                app.Slider.Value = 1;                %将滑块移动到起始位置
                app.Slider.Enable = 'on';            %启动滑块
            else
                return;
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            %%%%%%%%根据滑块位置不同画图
            Drawing(app);
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            [Filename,path] = uiputfile({'*.txt';'*.csv';'*.xlsx';'*.dat'},'Save Processed data','Processed_data.txt');%保存处理步骤至excel
            if Filename
            writematrix(app.Processing_data,[path,Filename]);
            end
        end

        % Value changed function: ColorDropDown
        function ColorDropDownValueChanged(app, event)
            value = app.ColorDropDown.Value;
            %%%%%%根据选择不同，调整图像色彩
            switch value
                case 'Gray'
                    colormap(app.UIAxes,gray);
                case 'Parula'
                    colormap(app.UIAxes,parula);
                case 'Turbo'
                    colormap(app.UIAxes,turbo);
                case 'Jet'
                    colormap(app.UIAxes,jet);
                case 'HSV'
                    colormap(app.UIAxes,hsv);
            end
        end

        % Button pushed function: YesButton4
        function YesButton4Pushed(app, event)
            %%%%%第一步，将数据传送给列表框对应的值，不行，这里需要用变量来保存，否则对不上
            app.A = app.Raw_data(:,floor(app.TracknumberEditField.Value));   %选择道的原始数据
            app.B = app.Processing_data(:,floor(app.TracknumberEditField.Value));  %选择道处理后的数据
            app.C = mean(app.Raw_data(:,app.BackgroundstartEditField.Value:app.BackgroundendEditField.Value),2);%选择的背景
            app.D = app.A-app.C;
            app.Plot_data = table(app.A,app.B,app.C,app.D);
            app.Plot_data.Properties.VariableNames = {'Raw data','Processed data','Background','Difference'};
            %%%%%第二步，频域参数计算机数据保存
            %%%%%频域参数计算
            Fs = app.M*1000/app.Time_window;%%%采样频率,单位MHz
            freP1 = Fs*(0:(app.M)/2)/app.M;%%成图所用的单边频率（NO模式），x坐标轴
            %%成图所用的去除直流的单边频率（YES模式，x坐标轴）（原始数据如果按位数存储，直流可能会很大，无法显示出个频率分量的相对值）
            
            
            freP11 = Fs*(1:(app.M)/2)/app.M;
            A_fft = fft(app.A);
            B_fft = fft(app.B);
            C_fft = fft(app.C);
            D_fft = fft(app.D);
            app.Spectrum_data = table(A_fft,B_fft,C_fft,D_fft);
            app.Spectrum_data.Properties.VariableNames = {'Raw data','Processed data','Background','Difference'};
            A_fftP2 = abs(A_fft/app.M);
            B_fftP2 = abs(B_fft/app.M);
            C_fftP2 = abs(C_fft/app.M);
            D_fftP2 = abs(D_fft/app.M);
            app.A_fftP1 = A_fftP2(1:app.M/2+1);
            app.B_fftP1 = B_fftP2(1:app.M/2+1);
            app.C_fftP1 = C_fftP2(1:app.M/2+1);
            app.D_fftP1 = D_fftP2(1:app.M/2+1);
            app.A_fftP1(2:end-1) = app.A_fftP1(2:end-1);  %成图所用的单侧频谱能量中间部分（值为FFT对应点的2/app.M），头尾为直流分量（值为FFT对应点的1/app.M）
            app.B_fftP1(2:end-1) = app.B_fftP1(2:end-1);
            app.C_fftP1(2:end-1) = app.C_fftP1(2:end-1);
            app.D_fftP1(2:end-1) = app.D_fftP1(2:end-1);
            app.A_fftP11 = app.A_fftP1(2:end);  %成图所用的单侧频谱能量中间部分（值为FFT对应点的2/N）
            app.B_fftP11 = app.B_fftP1(2:end);
            app.C_fftP11 = app.C_fftP1(2:end);
            app.D_fftP11 = app.D_fftP1(2:end);
            %%%%%第三步，成像，plot成像,频谱成像
            x = 0:app.Time_window/(app.M-1):app.Time_window;%指定plot的x轴
            if strcmp(app.ListBox.Value,'Raw data')
                plot(app.UIAxes2,x,app.A,'Color','g');
                if app.YESButton.Value == true
                    plot(app.UIAxes3,freP1,app.A_fftP1,'Color','g');
                elseif app.NOButton.Value == true
                    plot(app.UIAxes3,freP11,app.A_fftP11,'Color','g');
                end
            elseif strcmp(app.ListBox.Value,'Processed data')
                plot(app.UIAxes2,x,app.B,"Color",'r');
                if app.YESButton.Value == true
                    plot(app.UIAxes3,freP1,app.B_fftP1,'Color','r');
                elseif app.NOButton.Value == true
                    plot(app.UIAxes3,freP11,app.B_fftP11,'Color','r');
                end
            elseif strcmp(app.ListBox.Value,'Background')
                plot(app.UIAxes2,x,app.C,'Color','k');
                if app.YESButton.Value == true
                    plot(app.UIAxes3,freP1,app.C_fftP1,'Color','K');
                elseif app.NOButton.Value == true
                    plot(app.UIAxes3,freP11,app.C_fftP11,'Color','K');
                end
            elseif strcmp(app.ListBox.Value,'Difference')
                plot(app.UIAxes2,x,app.D,'Color','b');
                if app.YESButton.Value == true
                    plot(app.UIAxes3,freP1,app.D_fftP1,'Color','b');
                elseif app.NOButton.Value == true
                    plot(app.UIAxes3,freP11,app.D_fftP11,'Color','b');
                end
            end
            %%%%%第四步打开编辑限制
            app.SaveplotdataButton.Enable = 'on';%%打开保存框
            app.SavespectrumdataButton.Enable = 'on';%%打开保存框
            app.ListBox.Enable = 'on';           %%打开列表框
        end

        % Button pushed function: SaveplotdataButton
        function SaveplotdataButtonPushed(app, event)
            %%%%保存Plot_data
%             [Filename,~] = uiputfile({'*.txt';'*.csv';'*.xlsx'},'Save data','Plotdata.txt');%保存处理步骤至excel
%             if Filename
%                 writetable(app.Plot_data,Filename);
%             end
            [Filename,path] = uiputfile({'*.txt';'*.csv';'*.xlsx'},'Save data','Plotdata.txt');%保存处理步骤至excel
            if Filename
                writetable(app.Plot_data,[path,Filename]);
            end
%             app.SaveplotdataButton.Enable = 'off';
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            %%%%%在列表框处于编辑状态下，也可绘制图形
            x = 0:app.Time_window/(app.M-1):app.Time_window;%指定x轴
            Fs = app.M*1000/app.Time_window;%%%采样频率,单位MHz
            freP1 = Fs*(0:(app.M)/2)/app.M;%%成图所用的单边频率（NO模式），x坐标轴
            %%成图所用的去除直流的单边频率（YES模式，x坐标轴）（原始数据如果按位数存储，直流可能会很大，无法显示出个频率分量的相对值）
            freP11 = Fs*(1:(app.M)/2)/app.M;
            if strcmp(app.ListBox.Value,'Raw data')
                plot(app.UIAxes2,x,app.A,'Color','g');
                if app.YESButton.Value == true
                    plot(app.UIAxes3,freP1,app.A_fftP1,'Color','g');
                elseif app.NOButton.Value == true
                    plot(app.UIAxes3,freP11,app.A_fftP11,'Color','g');
                end
            elseif strcmp(app.ListBox.Value,'Processed data')
                plot(app.UIAxes2,x,app.B,"Color",'r');
                if app.YESButton.Value == true
                    plot(app.UIAxes3,freP1,app.B_fftP1,'Color','r');
                elseif app.NOButton.Value == true
                    plot(app.UIAxes3,freP11,app.B_fftP11,'Color','r');
                end
            elseif strcmp(app.ListBox.Value,'Background')
                plot(app.UIAxes2,x,app.C,'Color','k');
                if app.YESButton.Value == true
                    plot(app.UIAxes3,freP1,app.C_fftP1,'Color','K');
                elseif app.NOButton.Value == true
                    plot(app.UIAxes3,freP11,app.C_fftP11,'Color','K');
                end
            elseif strcmp(app.ListBox.Value,'Difference')
                plot(app.UIAxes2,x,app.D,'Color','b');
                if app.YESButton.Value == true
                    plot(app.UIAxes3,freP1,app.D_fftP1,'Color','b');
                elseif app.NOButton.Value == true
                    plot(app.UIAxes3,freP11,app.D_fftP11,'Color','b');
                end
            end
        end

        % Button pushed function: SavespectrumdataButton
        function SavespectrumdataButtonPushed(app, event)
%             [Filename,~] = uiputfile({'*.txt';'*.csv';'*.xlsx'},'Save data','Spectrum analysis.txt');%保存处理步骤至excel
%             if Filename
%                 writetable(app.Spectrum_data,Filename);
%             end
            [Filename,path] = uiputfile({'*.txt';'*.csv';'*.xlsx'},'Save data','Spectrum analysis.txt');%保存处理步骤至excel
            if Filename
                writetable(app.Spectrum_data,[path,Filename]);
            end            
%             app.SaveplotdataButton.Enable = 'off';
        end

        % Menu selected function: GainMenu
        function GainMenuSelected(app, event)
            app.GainMenu.Enable = 'off';
            app.Gain_app = Gain(app);
        end

        % Menu selected function: MathoperationsMenu
        function MathoperationsMenuSelected(app, event)
            app.MathoperationsMenu.Enable = 'off';
            app.Math_app = Math(app);
        end

        % Button pushed function: Reset_rawdataButton
        function Reset_rawdataButtonPushed(app, event)
            app.Processing_data = app.Raw_data;
            %%%%%%%%更新完处理后，更新图像区（重置的图像为整个数据成图）%%%%%%%%%
            app.UIAxes.XLim=[app.Start,app.End];
            X1 = app.Start:app.Sam_interval:app.End;                %横坐标表示里程
            Y1 = 0:app.Time_window/app.M:app.Time_window;           %纵坐标表示时间
            imagesc(app.UIAxes,X1,Y1,app.Processing_data);
            app.Slider.Value = app.N;
            app.Slider.Enable = 'off';
        end

        % Menu selected function: FilterMenu
        function FilterMenuSelected(app, event)
            app.FilterMenu.Enable = 'off';
            app.Filter_app = Filter(app);
        end

        % Menu selected function: TwoDfilterMenu
        function TwoDfilterMenuSelected(app, event)
            app.TwoDfilterMenu.Enable = 'off';
            app.TwoDfilter_app = TwoD_filter(app);
        end

        % Menu selected function: InstantaneousAmplitudeMenu
        function InstantaneousAmplitudeMenuSelected(app, event)
            Hl_Analysis = {1,'Amplitude',0,0,0,0,0,0,0,0};%注意，这里不能用字符串
            app.UITable2.Data = [app.UITable2.Data;Hl_Analysis];
            app.InstantaneousAmplitudeMenu.Enable = 'off';  %防止用户多次开启对话框,关闭
        end

        % Menu selected function: InstantaneousPhaseMenu
        function InstantaneousPhaseMenuSelected(app, event)
            Hl_Analysis = {1,'Phase',0,0,0,0,0,0,0,0};%注意，这里不能用字符串
            app.UITable2.Data = [app.UITable2.Data;Hl_Analysis];
            app.InstantaneousPhaseMenu.Enable = 'off';  %防止用户多次开启对话框，关闭
        end

        % Menu selected function: HilbertAnalysisMenu
        function HilbertAnalysisMenuSelected(app, event)
            app.InstantaneousAmplitudeMenu.Enable = 'on';  %防止用户多次开启对话框,开启
            app.InstantaneousPhaseMenu.Enable = 'on';  %防止用户多次开启对话框，开启
        end

        % Menu selected function: MigrationMenu
        function MigrationMenuSelected(app, event)
            app.SarMenu.Enable = 'on';  %防止用户多次开启对话框，开启
            app.MigrationMenu.Enable = 'off';  %防止用户多次开启对话框，开启
            app.Sar_app = Sar(app);
        end

        % Menu selected function: SarMenu
        function SarMenuSelected(app, event)
            app.SarMenu.Enable = 'off';
        end

        % Menu selected function: InstantaneousFrequencyMenu
        function InstantaneousFrequencyMenuSelected(app, event)
            Hl_Analysis = {1,'Frequency',0,0,0,0,0,0,0,0};%注意，这里不能用字符串
            app.UITable2.Data = [app.UITable2.Data;Hl_Analysis];
            app.InstantaneousFrequencyMenu.Enable = 'off';  %防止用户多次开启对话框，关闭
        end

        % Menu selected function: WaveletMenu
        function WaveletMenuSelected(app, event)
            app.WaveletMenu.Enable = 'off';
            app.Wavelet_app = Wavelet(app);%示例-2
        end

        % Button pushed function: YesButton4_2
        function YesButton4_2Pushed(app, event)
%%%%%第一步，将数据传送给列表框对应的值，不行，这里需要用变量来保存，否则对不上
            app.AA = app.Raw_data(:,floor(app.TracknumberEditField_2.Value));   %选择道的原始数据
            app.BB = app.Processing_data(:,floor(app.TracknumberEditField_2.Value));  %选择道处理后的数据
            app.CC = mean(app.Raw_data(:,app.BackgroundstartEditField_2.Value:app.BackgroundendEditField_2.Value),2);%选择的背景
            app.DD = app.AA-app.CC;
            app.TF_data = table(app.AA,app.BB,app.CC,app.DD);
            app.TF_data.Properties.VariableNames = {'Raw data','Processed data','Background','Difference'};
            %%%%%第二步，计算时频域和同步压缩域的数据
            %%%%%频域参数计算
            dt = app.Time_window*10^(-9)/(app.M-1);% 时间采样间隔                        
            [app.AA_SSGPST, ~,app.AA_GPST] = synsq_gpst_fw(app.AA, dt,app.GeneralparameterEditField.Value);
            [app.BB_SSGPST, ~,app.BB_GPST] = synsq_gpst_fw(app.BB, dt,app.GeneralparameterEditField.Value); 
            [app.CC_SSGPST, ~,app.CC_GPST] = synsq_gpst_fw(app.CC, dt,app.GeneralparameterEditField.Value);
            [app.DD_SSGPST, app.fs,app.DD_GPST] = synsq_gpst_fw(app.DD, dt,app.GeneralparameterEditField.Value);                        
            app.TF_GPST = table(app.AA_GPST,app.BB_GPST,app.CC_GPST,app.DD_GPST);
            app.TF_GPST.Properties.VariableNames = {'Raw data','Processed data','Background','Difference'};              
            app.TF_SSGPST = table(app.AA_SSGPST,app.BB_SSGPST,app.CC_SSGPST,app.DD_SSGPST);
            app.TF_SSGPST.Properties.VariableNames = {'Raw data','Processed data','Background','Difference'};  
            x = 0:app.Time_window/(app.M-1):app.Time_window;           %横坐标
            y=app.fs(1:app.Num_FpointsEditField.Value)/10^6;
            app.UIAxes4.XLim=[x(1),x(end)]; %限制X轴
            app.UIAxes5.XLim=[x(1),x(end)];
            app.UIAxes4.YLim=[y(1),y(end)]; %限制y轴
            app.UIAxes5.YLim=[y(1),y(end)];            
            if strcmp(app.ListBox.Value,'Raw data')
                imagesc(app.UIAxes4,x,y,abs(app.AA_GPST(1:app.Num_FpointsEditField.Value,:)));
                colorbar;
                imagesc(app.UIAxes5,x,y,abs(app.AA_SSGPST(1:app.Num_FpointsEditField.Value,:)));
                colorbar;
            elseif strcmp(app.ListBox.Value,'Processed data')
                imagesc(app.UIAxes4,x,y,abs(app.BB_GPST(1:app.Num_FpointsEditField.Value,:)));
                imagesc(app.UIAxes5,x,y,abs(app.BB_SSGPST(1:app.Num_FpointsEditField.Value,:)));
            elseif strcmp(app.ListBox.Value,'Background')
                imagesc(app.UIAxes4,x,y,abs(app.CC_GPST(1:app.Num_FpointsEditField.Value,:)));
                imagesc(app.UIAxes5,x,y,abs(app.CC_SSGPST(1:app.Num_FpointsEditField.Value,:)));
            elseif strcmp(app.ListBox.Value,'Difference')
                imagesc(app.UIAxes4,x,y,abs(app.DD_GPST(1:app.Num_FpointsEditField.Value,:)));
                imagesc(app.UIAxes5,x,y,abs(app.DD_SSGPST(1:app.Num_FpointsEditField.Value,:)));
            end
            app.UIAxes4.YDir='normal'; %翻转y轴
            app.UIAxes5.YDir='normal';
            %%%%%第四步打开编辑限制
            app.SaveTFdataButton.Enable = 'on';%%打开保存框
            app.SaveSCdataButton.Enable = 'on';%%打开保存框
            app.ListBox_2.Enable = 'on';           %%打开列表框            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: ListBox_2
        function ListBox_2ValueChanged(app, event)
            % 坐标轴的变化
            x = 0:app.Time_window/(app.M-1):app.Time_window;           %横坐标
            y=app.fs(1:app.Num_FpointsEditField.Value)/10^6;
            app.UIAxes4.XLim=[x(1),x(end)]; %限制X轴
            app.UIAxes5.XLim=[x(1),x(end)];
            app.UIAxes4.YLim=[y(1),y(end)]; %限制y轴
            app.UIAxes5.YLim=[y(1),y(end)];
            % 绘图
            if strcmp(app.ListBox_2.Value,'Raw data')
                imagesc(app.UIAxes4,x,y,abs(app.AA_GPST(1:app.Num_FpointsEditField.Value,:)));
                imagesc(app.UIAxes5,x,y,abs(app.AA_SSGPST(1:app.Num_FpointsEditField.Value,:)));
            elseif strcmp(app.ListBox_2.Value,'Processed data')
                imagesc(app.UIAxes4,x,y,abs(app.BB_GPST(1:app.Num_FpointsEditField.Value,:)));
                imagesc(app.UIAxes5,x,y,abs(app.BB_SSGPST(1:app.Num_FpointsEditField.Value,:)));
            elseif strcmp(app.ListBox_2.Value,'Background')
                imagesc(app.UIAxes4,x,y,abs(app.CC_GPST(1:app.Num_FpointsEditField.Value,:)));
                imagesc(app.UIAxes5,x,y,abs(app.CC_SSGPST(1:app.Num_FpointsEditField.Value,:)));
            elseif strcmp(app.ListBox_2.Value,'Difference')
                imagesc(app.UIAxes4,x,y,abs(app.DD_GPST(1:app.Num_FpointsEditField.Value,:)));
                imagesc(app.UIAxes5,x,y,abs(app.DD_SSGPST(1:app.Num_FpointsEditField.Value,:)));
            end
            app.UIAxes4.YDir='normal'; %翻转y轴
            app.UIAxes5.YDir='normal';
        end

        % Button pushed function: SaveTFdataButton
        function SaveTFdataButtonPushed(app, event)
            [Filename,path] = uiputfile({'*.txt';'*.csv';'*.xlsx'},'Save data','TF spectrum.txt');%保存处理步骤至excel
            if Filename
                writetable(app.TF_GPST,[path,Filename]);
            end   
        end

        % Button pushed function: SaveSCdataButton
        function SaveSCdataButtonPushed(app, event)
            [Filename,path] = uiputfile({'*.txt';'*.csv';'*.xlsx'},'Save data','SC spectrum.txt');%保存处理步骤至excel
            if Filename
                writetable(app.TF_SSGPST,[path,Filename]);
            end   
        end

        % Callback function
        function ContrastSpinnerValueChanged(app, event)
            
        end

        % Size changed function: ImageprocessingTab
        function ImageprocessingTabSizeChanged(app, event)

            
        end

        % Value changed function: ContrastEditField
        function ContrastEditFieldValueChanged(app, event)
            value = app.ContrastEditField.Value;
            max_value = max(app.Processing_data(:));
            min_value = min(app.Processing_data(:));
            caxis(app.UIAxes, [value*min_value, value*max_value]);           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GPRlabUIFigure = uifigure('Visible', 'off');
            app.GPRlabUIFigure.Name = 'GPRlab';
            app.GPRlabUIFigure.Icon = 'face.jpg';
            app.GPRlabUIFigure.CloseRequestFcn = createCallbackFcn(app, @GPRlabUIFigureCloseRequest, true);
            app.GPRlabUIFigure.KeyPressFcn = createCallbackFcn(app, @GPRlabUIFigureKeyPress, true);
            app.GPRlabUIFigure.Tag = '啦啦啦';
            app.R_DCMenu = uimenu(app.GPRlabUIFigure);
            app.R_DCMenu.MenuSelectedFcn = createCallbackFcn(app, @R_DCMenuSelected, true);
            app.R_DCMenu.Tooltip = {'去直流'};
            app.R_DCMenu.Text = 'R_DC';
            app.R_backgroundMenu = uimenu(app.GPRlabUIFigure);
            app.R_backgroundMenu.MenuSelectedFcn = createCallbackFcn(app, @R_backgroundMenuSelected, true);
            app.R_backgroundMenu.Tooltip = {'去背景'};
            app.R_backgroundMenu.Text = 'R_background';
            app.GainMenu = uimenu(app.GPRlabUIFigure);
            app.GainMenu.MenuSelectedFcn = createCallbackFcn(app, @GainMenuSelected, true);
            app.GainMenu.Tooltip = {'增益'};
            app.GainMenu.Text = 'Gain';
            app.Menu = uimenu(app.GainMenu);
            app.Menu.Text = 'Menu';
            app.MathoperationsMenu = uimenu(app.GPRlabUIFigure);
            app.MathoperationsMenu.MenuSelectedFcn = createCallbackFcn(app, @MathoperationsMenuSelected, true);
            app.MathoperationsMenu.Text = 'Math operations';
            app.FilterMenu = uimenu(app.GPRlabUIFigure);
            app.FilterMenu.MenuSelectedFcn = createCallbackFcn(app, @FilterMenuSelected, true);
            app.FilterMenu.Tooltip = {'一维滤波'};
            app.FilterMenu.Text = 'Filter';
            app.TwoDfilterMenu = uimenu(app.GPRlabUIFigure);
            app.TwoDfilterMenu.MenuSelectedFcn = createCallbackFcn(app, @TwoDfilterMenuSelected, true);
            app.TwoDfilterMenu.Tooltip = {'二维滤波'};
            app.TwoDfilterMenu.Text = 'TwoD   filter';
            app.HilbertAnalysisMenu = uimenu(app.GPRlabUIFigure);
            app.HilbertAnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @HilbertAnalysisMenuSelected, true);
            app.HilbertAnalysisMenu.Tooltip = {'希尔伯特属性分析'};
            app.HilbertAnalysisMenu.Text = 'Hilbert Analysis';
            app.InstantaneousAmplitudeMenu = uimenu(app.HilbertAnalysisMenu);
            app.InstantaneousAmplitudeMenu.MenuSelectedFcn = createCallbackFcn(app, @InstantaneousAmplitudeMenuSelected, true);
            app.InstantaneousAmplitudeMenu.Tooltip = {'瞬时振幅'};
            app.InstantaneousAmplitudeMenu.Text = 'Instantaneous Amplitude';
            app.InstantaneousPhaseMenu = uimenu(app.HilbertAnalysisMenu);
            app.InstantaneousPhaseMenu.MenuSelectedFcn = createCallbackFcn(app, @InstantaneousPhaseMenuSelected, true);
            app.InstantaneousPhaseMenu.Tooltip = {'瞬时相位'};
            app.InstantaneousPhaseMenu.Text = 'Instantaneous Phase';
            app.InstantaneousFrequencyMenu = uimenu(app.HilbertAnalysisMenu);
            app.InstantaneousFrequencyMenu.MenuSelectedFcn = createCallbackFcn(app, @InstantaneousFrequencyMenuSelected, true);
            app.InstantaneousFrequencyMenu.Text = 'Instantaneous Frequency';
            app.MigrationMenu = uimenu(app.GPRlabUIFigure);
            app.MigrationMenu.MenuSelectedFcn = createCallbackFcn(app, @MigrationMenuSelected, true);
            app.MigrationMenu.Tooltip = {'偏移算法'};
            app.MigrationMenu.Text = 'Migration';
            app.SarMenu = uimenu(app.MigrationMenu);
            app.SarMenu.MenuSelectedFcn = createCallbackFcn(app, @SarMenuSelected, true);
            app.SarMenu.Tooltip = {'合成孔径成像（类似时域BP）'};
            app.SarMenu.Text = 'Sar';
            app.WaveletMenu = uimenu(app.GPRlabUIFigure);
            app.WaveletMenu.MenuSelectedFcn = createCallbackFcn(app, @WaveletMenuSelected, true);
            app.WaveletMenu.Text = 'Wavelet';
            app.TabGroup = uitabgroup(app.GPRlabUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Tooltip = {''};
            app.DataTab.Title = 'Data';
            app.LoadPanel = uipanel(app.DataTab);
            app.LoadPanel.Title = 'LoadPanel';
            app.LoadButton = uibutton(app.LoadPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Tooltip = {'读入原始数据（.dat、.txt、.xlsx、.csv）'};
            app.LoadButton.Text = 'Load';
            app.DataimformationPanel = uipanel(app.LoadPanel);
            app.DataimformationPanel.Title = 'Data imformation ';
            app.AntennaMHzEditFieldLabel = uilabel(app.DataimformationPanel);
            app.AntennaMHzEditFieldLabel.Text = 'Antenna(MHz)';
            app.AntennaMHzEditField = uieditfield(app.DataimformationPanel, 'numeric');
            app.AntennaMHzEditField.Limits = [1 Inf];
            app.AntennaMHzEditField.Value = 300;
            app.RelativepermittivityEditFieldLabel = uilabel(app.DataimformationPanel);
            app.RelativepermittivityEditFieldLabel.Text = 'Relative permittivity';
            app.RelativepermittivityEditField = uieditfield(app.DataimformationPanel, 'numeric');
            app.RelativepermittivityEditField.Limits = [0.0001 81];
            app.RelativepermittivityEditField.Value = 9;
            app.TimewindownsEditFieldLabel = uilabel(app.DataimformationPanel);
            app.TimewindownsEditFieldLabel.Text = 'Time-window(ns)';
            app.TimewindownsEditField = uieditfield(app.DataimformationPanel, 'numeric');
            app.TimewindownsEditField.Limits = [0 Inf];
            app.TimewindownsEditField.Value = 60;
            app.SamplingintervalmLabel = uilabel(app.DataimformationPanel);
            app.SamplingintervalmLabel.Tooltip = {'如果用起始和终止位置计算采样间隔，请保持该值默认0'};
            app.SamplingintervalmLabel.Text = {'Sampling interval(m)'; ''};
            app.SamplingintervalmEditField = uieditfield(app.DataimformationPanel, 'numeric');
            app.SamplingintervalmEditField.Limits = [0 Inf];
            app.SamplingintervalmEditField.Value = 0.02;
            app.StartmLabel = uilabel(app.DataimformationPanel);
            app.StartmLabel.Text = 'Start(m)';
            app.StartmEditField = uieditfield(app.DataimformationPanel, 'numeric');
            app.StartmEditField.Limits = [0 Inf];
            app.StartmEditField.Tooltip = {'Start must be less than End '};
            app.EndmEditField = uieditfield(app.DataimformationPanel, 'numeric');
            app.EndmEditField.Limits = [0 Inf];
            app.EndmLabel = uilabel(app.DataimformationPanel);
            app.EndmLabel.Text = 'End(m)';
            app.TimezeronsEditField = uieditfield(app.DataimformationPanel, 'numeric');
            app.TimezeronsEditField.Limits = [0 Inf];
            app.TimezeronsEditField.Tooltip = {'Time-zero must be less than Time-window'};
            app.TimezeronsEditFieldLabel = uilabel(app.DataimformationPanel);
            app.TimezeronsEditFieldLabel.Text = 'Time-zero(ns)';
            app.YesButton = uibutton(app.DataimformationPanel, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton.Text = 'Yes';
            app.RawdataPanel = uipanel(app.DataTab);
            app.RawdataPanel.Tooltip = {'原始数据（与原始数据记录的一致）。'};
            app.RawdataPanel.Title = 'Raw data';
            app.UITable = uitable(app.RawdataPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.DatasizePanel = uipanel(app.DataTab);
            app.DatasizePanel.Title = 'Data size';
            app.RowsEditFieldLabel = uilabel(app.DatasizePanel);
            app.RowsEditFieldLabel.Text = 'Rows';
            app.RowsEditField = uieditfield(app.DatasizePanel, 'numeric');
            app.RowsEditField.Enable = 'off';
            app.RowsEditField.Tooltip = {'数据行数'};
            app.ColumnsEditFieldLabel = uilabel(app.DatasizePanel);
            app.ColumnsEditFieldLabel.Text = 'Columns';
            app.ColumnsEditField = uieditfield(app.DatasizePanel, 'numeric');
            app.ColumnsEditField.Enable = 'off';
            app.ColumnsEditField.Tooltip = {'数据列数'};
            app.MileagemEditFieldLabel = uilabel(app.DatasizePanel);
            app.MileagemEditFieldLabel.Text = 'Mileage(m)';
            app.MileagemEditField = uieditfield(app.DatasizePanel, 'numeric');
            app.MileagemEditField.Enable = 'off';
            app.MileagemEditField.Tooltip = {'数据总长'};
            app.DepthmEditFieldLabel = uilabel(app.DatasizePanel);
            app.DepthmEditFieldLabel.Text = 'Depth(m)';
            app.DepthmEditField = uieditfield(app.DatasizePanel, 'numeric');
            app.DepthmEditField.Enable = 'off';
            app.DepthmEditField.Tooltip = {'考虑了数据零点后的深度'};
            app.ImageprocessingTab = uitab(app.TabGroup);
            app.ImageprocessingTab.Tooltip = {''};
            app.ImageprocessingTab.SizeChangedFcn = createCallbackFcn(app, @ImageprocessingTabSizeChanged, true);
            app.ImageprocessingTab.Title = 'Image processing';
            app.ProcessingPanel = uipanel(app.ImageprocessingTab);
            app.ProcessingPanel.Title = 'Processing';
            app.UITable2 = uitable(app.ProcessingPanel);
            app.UITable2.ColumnName = {'Activation'; 'Processing'; ''; ''};
            app.UITable2.RowName = {};
            app.UITable2.CellSelectionCallback = createCallbackFcn(app, @UITable2CellSelection, true);
            app.upButton = uibutton(app.ProcessingPanel, 'push');
            app.upButton.ButtonPushedFcn = createCallbackFcn(app, @upButtonPushed, true);
            app.upButton.Tooltip = {'上移流程'};
            app.upButton.Text = 'up';
            app.downButton = uibutton(app.ProcessingPanel, 'push');
            app.downButton.ButtonPushedFcn = createCallbackFcn(app, @downButtonPushed, true);
            app.downButton.Tooltip = {'下移流程'};
            app.downButton.Text = 'down';
            app.deleteButton = uibutton(app.ProcessingPanel, 'push');
            app.deleteButton.ButtonPushedFcn = createCallbackFcn(app, @deleteButtonPushed, true);
            app.deleteButton.Tooltip = {'删除流程'};
            app.deleteButton.Text = 'delete';
            app.LoadprocessingButton = uibutton(app.ProcessingPanel, 'push');
            app.LoadprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @LoadprocessingButtonPushed, true);
            app.LoadprocessingButton.Tooltip = {'读入处理流程'};
            app.LoadprocessingButton.Text = 'Load processing';
            app.SaveprocessingButton = uibutton(app.ProcessingPanel, 'push');
            app.SaveprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveprocessingButtonPushed, true);
            app.SaveprocessingButton.Tooltip = {'保存处理流程'};
            app.SaveprocessingButton.Text = 'Save processing';
            app.YesButton_2 = uibutton(app.ProcessingPanel, 'push');
            app.YesButton_2.ButtonPushedFcn = createCallbackFcn(app, @YesButton_2Pushed, true);
            app.YesButton_2.Tooltip = {'确认处理流程正确后，点击Yes！。'};
            app.YesButton_2.Text = 'Yes';
            app.Reset_rawdataButton = uibutton(app.ProcessingPanel, 'push');
            app.Reset_rawdataButton.ButtonPushedFcn = createCallbackFcn(app, @Reset_rawdataButtonPushed, true);
            app.Reset_rawdataButton.Tooltip = {'处理后的数据重置为原始数据。'};
            app.Reset_rawdataButton.Text = 'Reset_rawdata';
            app.SavethicknessButton = uibutton(app.ImageprocessingTab, 'push');
            app.SavethicknessButton.Enable = 'off';
            app.SavethicknessButton.Tooltip = {'保存厚度表'};
            app.SavethicknessButton.Text = 'Save thickness';
            app.SaveDataButton = uibutton(app.ImageprocessingTab, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Tooltip = {'保存处理后的数据'};
            app.SaveDataButton.Text = 'Save Data';
            app.YesButton_3 = uibutton(app.ImageprocessingTab, 'push');
            app.YesButton_3.ButtonPushedFcn = createCallbackFcn(app, @YesButton_3Pushed, true);
            app.YesButton_3.Tooltip = {'编辑完通道数或距离后，点击Yes！'};
            app.YesButton_3.Text = 'Yes';
            app.ColorDropDownLabel = uilabel(app.ImageprocessingTab);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.ImageprocessingTab);
            app.ColorDropDown.Items = {'Gray', 'Parula', 'Turbo', 'Jet', 'HSV'};
            app.ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorDropDownValueChanged, true);
            app.ColorDropDown.Tooltip = {'选择色彩模式'};
            app.ColorDropDown.Value = 'Gray';
            app.TraceLengthEditFieldLabel = uilabel(app.ImageprocessingTab);
            app.TraceLengthEditFieldLabel.Text = 'Trace Length';
            app.TraceLengthEditField = uieditfield(app.ImageprocessingTab, 'numeric');
            app.TraceLengthEditField.Limits = [10 Inf];
            app.TraceLengthEditField.ValueDisplayFormat = '%.0f';
            app.TraceLengthEditField.ValueChangedFcn = createCallbackFcn(app, @TraceLengthEditFieldValueChanged, true);
            app.TraceLengthEditField.Tooltip = {'当前页面的道数。输入必须是整数，大于0，小于等于数据总道数。注意：默认为0，表示显示整个数据。'};
            app.TraceLengthEditField.Value = 10;
            app.DistancemEditFieldLabel = uilabel(app.ImageprocessingTab);
            app.DistancemEditFieldLabel.Text = 'Distance(m)';
            app.DistancemEditField = uieditfield(app.ImageprocessingTab, 'numeric');
            app.DistancemEditField.Limits = [0 Inf];
            app.DistancemEditField.ValueChangedFcn = createCallbackFcn(app, @DistancemEditFieldValueChanged, true);
            app.DistancemEditField.Tooltip = {'当前页面，一幅图的水平距离。'};
            app.Slider = uislider(app.ImageprocessingTab);
            app.Slider.Limits = [1 100];
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.Slider.Value = 1;
            app.ContrastEditFieldLabel = uilabel(app.ImageprocessingTab);
            app.ContrastEditFieldLabel.Text = 'Contrast';
            app.ContrastEditField = uieditfield(app.ImageprocessingTab, 'numeric');
            app.ContrastEditField.Limits = [0.01 10];
            app.ContrastEditField.ValueChangedFcn = createCallbackFcn(app, @ContrastEditFieldValueChanged, true);
            app.ContrastEditField.Tooltip = {'对比度0.1-10之间设置'};
            app.ContrastEditField.Value = 1;
            app.UIAxes = uiaxes(app.ImageprocessingTab);
            xlabel(app.UIAxes, 'mileage(m)')
            ylabel(app.UIAxes, 'Time(ns)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.XAxisLocation = 'top';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.WaveTab = uitab(app.TabGroup);
            app.WaveTab.Tooltip = {''};
            app.WaveTab.Title = 'Wave';
            app.TracknumberEditFieldLabel = uilabel(app.WaveTab);
            app.TracknumberEditFieldLabel.Text = 'Track number';
            app.TracknumberEditField = uieditfield(app.WaveTab, 'numeric');
            app.TracknumberEditField.Limits = [1 Inf];
            app.TracknumberEditField.ValueDisplayFormat = '%.0f';
            app.TracknumberEditField.Tooltip = {'数据道'};
            app.TracknumberEditField.Value = 1;
            app.ListBox = uilistbox(app.WaveTab);
            app.ListBox.Items = {'Raw data', 'Processed data', 'Background', 'Difference'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Enable = 'off';
            app.ListBox.Tooltip = {'原始数据；'; '处理后的数据；'; '背景数据（；'; '差值（原始数据与背景的差值）。'};
            app.ListBox.Value = 'Raw data';
            app.YesButton4 = uibutton(app.WaveTab, 'push');
            app.YesButton4.ButtonPushedFcn = createCallbackFcn(app, @YesButton4Pushed, true);
            app.YesButton4.Enable = 'off';
            app.YesButton4.Tooltip = {'编辑完参数后，请点击Yes！'};
            app.YesButton4.Text = 'Yes';
            app.SaveplotdataButton = uibutton(app.WaveTab, 'push');
            app.SaveplotdataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveplotdataButtonPushed, true);
            app.SaveplotdataButton.Enable = 'off';
            app.SaveplotdataButton.Text = 'Save plot data';
            app.SavespectrumdataButton = uibutton(app.WaveTab, 'push');
            app.SavespectrumdataButton.ButtonPushedFcn = createCallbackFcn(app, @SavespectrumdataButtonPushed, true);
            app.SavespectrumdataButton.Enable = 'off';
            app.SavespectrumdataButton.Text = 'Save spectrum data';
            app.BackgroundstartEditFieldLabel = uilabel(app.WaveTab);
            app.BackgroundstartEditFieldLabel.Text = 'Background start';
            app.BackgroundstartEditField = uieditfield(app.WaveTab, 'numeric');
            app.BackgroundstartEditField.Limits = [1 Inf];
            app.BackgroundstartEditField.ValueDisplayFormat = '%.0f';
            app.BackgroundstartEditField.Tooltip = {'开始数据道'};
            app.BackgroundstartEditField.Value = 1;
            app.BackgroundendEditFieldLabel = uilabel(app.WaveTab);
            app.BackgroundendEditFieldLabel.Text = 'Background end';
            app.BackgroundendEditField = uieditfield(app.WaveTab, 'numeric');
            app.BackgroundendEditField.Limits = [2 Inf];
            app.BackgroundendEditField.ValueDisplayFormat = '%.0f';
            app.BackgroundendEditField.Tooltip = {'结束数据道'};
            app.BackgroundendEditField.Value = 2;
            app.DCButtonGroup = uibuttongroup(app.WaveTab);
            app.DCButtonGroup.Tooltip = {'频谱中是否显示直流分量。由于数据采用二进制位数保存，直流分量可能很大，使得其他频率较为不直观'};
            app.DCButtonGroup.Title = 'DC';
            app.NOButton = uiradiobutton(app.DCButtonGroup);
            app.NOButton.Text = 'NO';
            app.NOButton.Value = true;
            app.YESButton = uiradiobutton(app.DCButtonGroup);
            app.YESButton.Text = 'YES';
            app.UIAxes2 = uiaxes(app.WaveTab);
            title(app.UIAxes2, 'Plot')
            xlabel(app.UIAxes2, 'Time(ns)')
            ylabel(app.UIAxes2, 'value')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.WaveTab);
            title(app.UIAxes3, 'Spectrum analysis')
            xlabel(app.UIAxes3, 'Frequency(MHz)')
            ylabel(app.UIAxes3, 'value')
            zlabel(app.UIAxes3, 'Z')
            app.TimefrequencyanalysisTab = uitab(app.TabGroup);
            app.TimefrequencyanalysisTab.Tooltip = {'In Time–frequency analysis, we use the phase-shifting generalized S transform and its corresponding synchronous compression transform. You can use them as generalized S transformations and synchronous generalized S transformations.'};
            app.TimefrequencyanalysisTab.Title = 'Time-frequency analysis';
            app.ListBox_2 = uilistbox(app.TimefrequencyanalysisTab);
            app.ListBox_2.Items = {'Raw data', 'Processed data', 'Background', 'Difference'};
            app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged, true);
            app.ListBox_2.Enable = 'off';
            app.ListBox_2.Tooltip = {'原始数据；'; '处理后的数据；'; '背景数据（；'; '差值（原始数据与背景的差值）。'};
            app.ListBox_2.Value = 'Raw data';
            app.TracknumberEditField_2Label = uilabel(app.TimefrequencyanalysisTab);
            app.TracknumberEditField_2Label.Text = 'Track number';
            app.TracknumberEditField_2 = uieditfield(app.TimefrequencyanalysisTab, 'numeric');
            app.TracknumberEditField_2.ValueDisplayFormat = '%.0f';
            app.TracknumberEditField_2.Tooltip = {'数据道'};
            app.TracknumberEditField_2.Value = 1;
            app.BackgroundstartEditField_2Label = uilabel(app.TimefrequencyanalysisTab);
            app.BackgroundstartEditField_2Label.Text = 'Background start';
            app.BackgroundstartEditField_2 = uieditfield(app.TimefrequencyanalysisTab, 'numeric');
            app.BackgroundstartEditField_2.Limits = [1 Inf];
            app.BackgroundstartEditField_2.ValueDisplayFormat = '%.0f';
            app.BackgroundstartEditField_2.Tooltip = {'开始数据道'};
            app.BackgroundstartEditField_2.Value = 1;
            app.BackgroundendEditField_2Label = uilabel(app.TimefrequencyanalysisTab);
            app.BackgroundendEditField_2Label.Text = 'Background end';
            app.BackgroundendEditField_2 = uieditfield(app.TimefrequencyanalysisTab, 'numeric');
            app.BackgroundendEditField_2.Limits = [1 Inf];
            app.BackgroundendEditField_2.ValueDisplayFormat = '%.0f';
            app.BackgroundendEditField_2.Tooltip = {'结束数据道'};
            app.BackgroundendEditField_2.Value = 2;
            app.ParameterPGSTPanel = uipanel(app.TimefrequencyanalysisTab);
            app.ParameterPGSTPanel.Title = 'Parameter (PGST)';
            app.Num_FpointsEditFieldLabel = uilabel(app.ParameterPGSTPanel);
            app.Num_FpointsEditFieldLabel.Text = 'Num_Fpoints';
            app.Num_FpointsEditField = uieditfield(app.ParameterPGSTPanel, 'numeric');
            app.Num_FpointsEditField.Limits = [1 Inf];
            app.Num_FpointsEditField.ValueDisplayFormat = '%.0f';
            app.Num_FpointsEditField.Tooltip = {'不要超过每道的1/2长度。'};
            app.Num_FpointsEditField.Value = 100;
            app.GeneralparameterEditFieldLabel = uilabel(app.ParameterPGSTPanel);
            app.GeneralparameterEditFieldLabel.Text = 'General parameter';
            app.GeneralparameterEditField = uieditfield(app.ParameterPGSTPanel, 'numeric');
            app.GeneralparameterEditField.Limits = [0.1 10];
            app.GeneralparameterEditField.Tooltip = {'可以认为是广义S变换的广义参数。'};
            app.GeneralparameterEditField.Value = 2;
            app.YesButton4_2 = uibutton(app.ParameterPGSTPanel, 'push');
            app.YesButton4_2.ButtonPushedFcn = createCallbackFcn(app, @YesButton4_2Pushed, true);
            app.YesButton4_2.Tooltip = {'编辑完参数后，请点击Yes！'};
            app.YesButton4_2.Text = 'Yes';
            app.SaveTFdataButton = uibutton(app.TimefrequencyanalysisTab, 'push');
            app.SaveTFdataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTFdataButtonPushed, true);
            app.SaveTFdataButton.Enable = 'off';
            app.SaveTFdataButton.Text = 'Save TF data';
            app.SaveSCdataButton = uibutton(app.TimefrequencyanalysisTab, 'push');
            app.SaveSCdataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSCdataButtonPushed, true);
            app.SaveSCdataButton.Enable = 'off';
            app.SaveSCdataButton.Text = 'Save SC data';
            app.UIAxes4 = uiaxes(app.TimefrequencyanalysisTab);
            title(app.UIAxes4, 'Time frequency domain')
            xlabel(app.UIAxes4, 'Time (ns)')
            ylabel(app.UIAxes4, 'Frequency (MHz)')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.YDir = 'reverse';
            app.UIAxes5 = uiaxes(app.TimefrequencyanalysisTab);
            title(app.UIAxes5, 'Synchronous compression domain')
            xlabel(app.UIAxes5, 'Time (ns)')
            ylabel(app.UIAxes5, 'Frequency (MHz)')
            zlabel(app.UIAxes5, 'Z')
            % Show the figure after all components are created
            app.GPRlabUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GPRlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GPRlabUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GPRlabUIFigure)
        end
    end
end