classdef Gain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ExponentgainPanel           matlab.ui.container.Panel
        scaleEditFieldLabel         matlab.ui.control.Label
        scaleEditField              matlab.ui.control.NumericEditField
        exponentEditFieldLabel      matlab.ui.control.Label
        exponentEditField           matlab.ui.control.NumericEditField
        starttimesEditFieldLabel    matlab.ui.control.Label
        starttimesEditField         matlab.ui.control.NumericEditField
        endtimesEditFieldLabel      matlab.ui.control.Label
        endtimesEditField           matlab.ui.control.NumericEditField
        YesButton                   matlab.ui.control.Button
        LinerautomaticgainPanel     matlab.ui.container.Panel
        lengthEditFieldLabel        matlab.ui.control.Label
        lengthEditField             matlab.ui.control.NumericEditField
        YesButton_2                 matlab.ui.control.Button
        AveragevalueEditFieldLabel  matlab.ui.control.Label
        AveragevalueEditField       matlab.ui.control.NumericEditField
        ManualgainPanel             matlab.ui.container.Panel
        YesButton_3                 matlab.ui.control.Button
        pointsEditFieldLabel        matlab.ui.control.Label
        pointsEditField             matlab.ui.control.EditField
        valuetimesEditFieldLabel    matlab.ui.control.Label
        valuetimesEditField         matlab.ui.control.EditField
        RMSautomaticgainPanel       matlab.ui.container.Panel
        lengthEditField_2Label      matlab.ui.control.Label
        lengthEditField_2           matlab.ui.control.NumericEditField
        YesButton_4                 matlab.ui.control.Button
        GRMSautomaticgainPanel      matlab.ui.container.Panel
        lengthEditField_3Label      matlab.ui.control.Label
        lengthEditField_3           matlab.ui.control.NumericEditField
        YesButton_5                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingApp                  % Description 主函数对应的属性
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.callingApp = mainapp; 
        end

        % Button pushed function: YesButton
        function YesButtonPushed(app, event)
            Processing_Plus(app.callingApp,1,app.ExponentgainPanel.Title,app.scaleEditField.Value,app.exponentEditField.Value,app.starttimesEditField.Value,app.endtimesEditField.Value,0,0,0,0);
        end

        % Button pushed function: YesButton_2
        function YesButton_2Pushed(app, event)


            Processing_Plus(app.callingApp,1,app.LinerautomaticgainPanel.Title,app.lengthEditField.Value,app.AveragevalueEditField.Value,0,0,0,0,0,0);
        end

        % Button pushed function: YesButton_3
        function YesButton_3Pushed(app, event)
            Processing_Plus(app.callingApp,1,app.ManualgainPanel.Title,app.pointsEditField.Value,app.valuetimesEditField.Value,0,0,0,0,0,0);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingApp.GainMenu.Enable = 'on';%%%%关闭对话框，就会让增益步骤激活
            delete(app)
        end

        % Button pushed function: YesButton_4
        function YesButton_4Pushed(app, event)
             Processing_Plus(app.callingApp,1,app.RMSautomaticgainPanel.Title,app.lengthEditField_2.Value,0,0,0,0,0,0,0);  
        end

        % Button pushed function: YesButton_5
        function YesButton_5Pushed(app, event)
             Processing_Plus(app.callingApp,1,app.GRMSautomaticgainPanel.Title,app.lengthEditField_3.Value,0,0,0,0,0,0,0); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'face.jpg';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExponentgainPanel = uipanel(app.UIFigure);
            app.ExponentgainPanel.Tooltip = {'增益函数y = scale*x.^(exponent);'; 'x矩阵由开始增益倍数、结束增益倍数和exponent反算得出。'};
            app.ExponentgainPanel.Title = 'Exponent gain';
            app.scaleEditFieldLabel = uilabel(app.ExponentgainPanel);
            app.scaleEditFieldLabel.Text = 'scale';
            app.scaleEditField = uieditfield(app.ExponentgainPanel, 'numeric');
            app.scaleEditField.Limits = [0 Inf];
            app.scaleEditField.Value = 1;
            app.exponentEditFieldLabel = uilabel(app.ExponentgainPanel);
            app.exponentEditFieldLabel.Text = 'exponent';
            app.exponentEditField = uieditfield(app.ExponentgainPanel, 'numeric');
            app.exponentEditField.Limits = [0 Inf];
            app.exponentEditField.Value = 2;
            app.starttimesEditFieldLabel = uilabel(app.ExponentgainPanel);
            app.starttimesEditFieldLabel.Text = 'start(times)';
            app.starttimesEditField = uieditfield(app.ExponentgainPanel, 'numeric');
            app.starttimesEditField.Limits = [0 Inf];
            app.starttimesEditField.Value = 1;
            app.endtimesEditFieldLabel = uilabel(app.ExponentgainPanel);
            app.endtimesEditFieldLabel.Text = 'end(times)';
            app.endtimesEditField = uieditfield(app.ExponentgainPanel, 'numeric');
            app.endtimesEditField.Limits = [0 Inf];
            app.endtimesEditField.Value = 1000;
            app.YesButton = uibutton(app.ExponentgainPanel, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton.Text = 'Yes';
            app.LinerautomaticgainPanel = uipanel(app.UIFigure);
            app.LinerautomaticgainPanel.Tooltip = {'自动增益'};
            app.LinerautomaticgainPanel.Title = 'Liner automatic gain ';
            app.lengthEditFieldLabel = uilabel(app.LinerautomaticgainPanel);
            app.lengthEditFieldLabel.Text = 'length';
            app.lengthEditField = uieditfield(app.LinerautomaticgainPanel, 'numeric');
            app.lengthEditField.Limits = [1 Inf];
            app.lengthEditField.ValueDisplayFormat = '%.0f';
            app.lengthEditField.Tooltip = {'窗口长度，大于2小于等于采样点数。窗口与窗口间重叠50%。'};
            app.lengthEditField.Value = 70;
            app.YesButton_2 = uibutton(app.LinerautomaticgainPanel, 'push');
            app.YesButton_2.ButtonPushedFcn = createCallbackFcn(app, @YesButton_2Pushed, true);
            app.YesButton_2.Text = 'Yes';
            app.AveragevalueEditFieldLabel = uilabel(app.LinerautomaticgainPanel);
            app.AveragevalueEditFieldLabel.Text = 'Average value';
            app.AveragevalueEditField = uieditfield(app.LinerautomaticgainPanel, 'numeric');
            app.AveragevalueEditField.Tooltip = {'窗口内均值'};
            app.AveragevalueEditField.Value = 3000;
            app.ManualgainPanel = uipanel(app.UIFigure);
            app.ManualgainPanel.Tooltip = {'人工增益，points的数目必须与value数目一致。points的第一个数必须是1，最后一个数必须是纵向采样点数。'};
            app.ManualgainPanel.Title = 'Manual gain';
            app.YesButton_3 = uibutton(app.ManualgainPanel, 'push');
            app.YesButton_3.ButtonPushedFcn = createCallbackFcn(app, @YesButton_3Pushed, true);
            app.YesButton_3.Text = 'Yes';
            app.pointsEditFieldLabel = uilabel(app.ManualgainPanel);
            app.pointsEditFieldLabel.Text = 'points';
            app.pointsEditField = uieditfield(app.ManualgainPanel, 'text');
            app.pointsEditField.Tooltip = {'增益的分割点。输入一个至少包括起止点的矩阵。点与点之间采用线性插值。'};
            app.pointsEditField.Value = '1,512';
            app.valuetimesEditFieldLabel = uilabel(app.ManualgainPanel);
            app.valuetimesEditFieldLabel.Text = 'value(times)';
            app.valuetimesEditField = uieditfield(app.ManualgainPanel, 'text');
            app.valuetimesEditField.Tooltip = {'分割点增益的倍数。请输入矩阵。矩阵长度应和point保持一致'};
            app.valuetimesEditField.Value = '1,100';
            app.RMSautomaticgainPanel = uipanel(app.UIFigure);
            app.RMSautomaticgainPanel.Tooltip = {'自动增益'};
            app.RMSautomaticgainPanel.Title = 'RMS automatic gain';
            app.lengthEditField_2Label = uilabel(app.RMSautomaticgainPanel);
            app.lengthEditField_2Label.Text = 'length';
            app.lengthEditField_2 = uieditfield(app.RMSautomaticgainPanel, 'numeric');
            app.lengthEditField_2.Tooltip = {'窗口长度，大于2小于等于采样点数。数值越小，相对强振幅压制越厉害。'};
            app.lengthEditField_2.Value = 40;
            app.YesButton_4 = uibutton(app.RMSautomaticgainPanel, 'push');
            app.YesButton_4.ButtonPushedFcn = createCallbackFcn(app, @YesButton_4Pushed, true);
            app.YesButton_4.Text = 'Yes';
            app.GRMSautomaticgainPanel = uipanel(app.UIFigure);
            app.GRMSautomaticgainPanel.Tooltip = {'自动增益'};
            app.GRMSautomaticgainPanel.Title = 'GRMS automatic gain';
            app.lengthEditField_3Label = uilabel(app.GRMSautomaticgainPanel);
            app.lengthEditField_3Label.Text = 'length';
            app.lengthEditField_3 = uieditfield(app.GRMSautomaticgainPanel, 'numeric');
            app.lengthEditField_3.Tooltip = {'窗口长度，大于2小于等于采样点数。数值越小增益越强。'};
            app.lengthEditField_3.Value = 40;
            app.YesButton_5 = uibutton(app.GRMSautomaticgainPanel, 'push');
            app.YesButton_5.ButtonPushedFcn = createCallbackFcn(app, @YesButton_5Pushed, true);
            app.YesButton_5.Text = 'Yes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Gain(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end