classdef Math < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        PlusPanel              matlab.ui.container.Panel
        valueEditFieldLabel    matlab.ui.control.Label
        valueEditField         matlab.ui.control.NumericEditField
        YesButton              matlab.ui.control.StateButton
        MultiplicationPanel    matlab.ui.container.Panel
        timesEditFieldLabel    matlab.ui.control.Label
        timesEditField         matlab.ui.control.NumericEditField
        YesButton_2            matlab.ui.control.StateButton
        AbsolutePanel          matlab.ui.container.Panel
        timesEditField_2Label  matlab.ui.control.Label
        timesEditField_2       matlab.ui.control.NumericEditField
        YesButton_3            matlab.ui.control.StateButton
        SquarePanel            matlab.ui.container.Panel
        timesEditField_3Label  matlab.ui.control.Label
        timesEditField_3       matlab.ui.control.NumericEditField
        YesButton_4            matlab.ui.control.StateButton
        GradientPanel          matlab.ui.container.Panel
        scaleEditFieldLabel    matlab.ui.control.Label
        scaleEditField         matlab.ui.control.NumericEditField
        YesButton_5            matlab.ui.control.StateButton
        pointsEditFieldLabel   matlab.ui.control.Label
        pointsEditField        matlab.ui.control.NumericEditField
        ButtonGroup            matlab.ui.container.ButtonGroup
        downButton             matlab.ui.control.RadioButton
        upButton               matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        callingapp % Description，主app对应的属性
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.callingapp = mainapp; %保存主app
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingapp.MathoperationsMenu.Enable = 'on';%开启主app数学运算模块
            delete(app)
        end

        % Value changed function: YesButton
        function YesButtonValueChanged(app, event)
            Processing_Plus(app.callingapp,1,app.PlusPanel.Title,app.valueEditField.Value,0,0,0,0,0,0,0);
        end

        % Value changed function: YesButton_2
        function YesButton_2ValueChanged(app, event)
              Processing_Plus(app.callingapp,1,app.MultiplicationPanel.Title,app.timesEditField.Value,0,0,0,0,0,0,0);
        end

        % Value changed function: YesButton_3
        function YesButton_3ValueChanged(app, event)
                Processing_Plus(app.callingapp,1,app.AbsolutePanel.Title,app.timesEditField_2.Value,0,0,0,0,0,0,0);
        end

        % Value changed function: YesButton_4
        function YesButton_4ValueChanged(app, event)
            Processing_Plus(app.callingapp,1,app.SquarePanel.Title,app.timesEditField_3.Value,0,0,0,0,0,0,0);
        end

        % Value changed function: YesButton_5
        function YesButton_5ValueChanged(app, event)
            if app.upButton.Value
                Processing_Plus(app.callingapp,1,app.GradientPanel.Title,app.upButton.Text,app.scaleEditField.Value,app.pointsEditField.Value,0,0,0,0,0);
            elseif app.downButton.Value
                Processing_Plus(app.callingapp,1,app.GradientPanel.Title,app.downButton.Text,app.scaleEditField.Value,app.pointsEditField.Value,0,0,0,0,0);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'face.jpg';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PlusPanel = uipanel(app.UIFigure);
            app.PlusPanel.Tooltip = {'加法'};
            app.PlusPanel.Title = 'Plus';
            app.valueEditFieldLabel = uilabel(app.PlusPanel);
            app.valueEditFieldLabel.Text = 'value';
            app.valueEditField = uieditfield(app.PlusPanel, 'numeric');
            app.YesButton = uibutton(app.PlusPanel, 'state');
            app.YesButton.ValueChangedFcn = createCallbackFcn(app, @YesButtonValueChanged, true);
            app.YesButton.Text = 'Yes';
            app.MultiplicationPanel = uipanel(app.UIFigure);
            app.MultiplicationPanel.Tooltip = {'乘法'};
            app.MultiplicationPanel.Title = 'Multiplication';
            app.timesEditFieldLabel = uilabel(app.MultiplicationPanel);
            app.timesEditFieldLabel.Text = 'times';
            app.timesEditField = uieditfield(app.MultiplicationPanel, 'numeric');
            app.timesEditField.Value = 1;
            app.YesButton_2 = uibutton(app.MultiplicationPanel, 'state');
            app.YesButton_2.ValueChangedFcn = createCallbackFcn(app, @YesButton_2ValueChanged, true);
            app.YesButton_2.Text = 'Yes';
            app.AbsolutePanel = uipanel(app.UIFigure);
            app.AbsolutePanel.Tooltip = {'绝对值，times代表取绝对值后乘以的倍数。'};
            app.AbsolutePanel.Title = 'Absolute';
            app.timesEditField_2Label = uilabel(app.AbsolutePanel);
            app.timesEditField_2Label.Text = 'times';
            app.timesEditField_2 = uieditfield(app.AbsolutePanel, 'numeric');
            app.timesEditField_2.Value = 1;
            app.YesButton_3 = uibutton(app.AbsolutePanel, 'state');
            app.YesButton_3.ValueChangedFcn = createCallbackFcn(app, @YesButton_3ValueChanged, true);
            app.YesButton_3.Text = 'Yes';
            app.SquarePanel = uipanel(app.UIFigure);
            app.SquarePanel.Tooltip = {'开方(虚数则变为虚数的范式)，times代表开方后的倍数。'};
            app.SquarePanel.Title = 'Square';
            app.timesEditField_3Label = uilabel(app.SquarePanel);
            app.timesEditField_3Label.Text = 'times';
            app.timesEditField_3 = uieditfield(app.SquarePanel, 'numeric');
            app.timesEditField_3.Value = 1;
            app.YesButton_4 = uibutton(app.SquarePanel, 'state');
            app.YesButton_4.ValueChangedFcn = createCallbackFcn(app, @YesButton_4ValueChanged, true);
            app.YesButton_4.Text = 'Yes';
            app.GradientPanel = uipanel(app.UIFigure);
            app.GradientPanel.Tooltip = {'梯度，每道数据减去上下一定points后的数据，乘scale。'};
            app.GradientPanel.Title = 'Gradient';
            app.scaleEditFieldLabel = uilabel(app.GradientPanel);
            app.scaleEditFieldLabel.Text = 'scale';
            app.scaleEditField = uieditfield(app.GradientPanel, 'numeric');
            app.scaleEditField.Value = 1;
            app.YesButton_5 = uibutton(app.GradientPanel, 'state');
            app.YesButton_5.ValueChangedFcn = createCallbackFcn(app, @YesButton_5ValueChanged, true);
            app.YesButton_5.Text = 'Yes';
            app.pointsEditFieldLabel = uilabel(app.GradientPanel);
            app.pointsEditFieldLabel.Text = 'points';
            app.pointsEditField = uieditfield(app.GradientPanel, 'numeric');
            app.pointsEditField.Value = 1;
            app.ButtonGroup = uibuttongroup(app.GradientPanel);
            app.downButton = uiradiobutton(app.ButtonGroup);
            app.downButton.Text = 'down';
            app.downButton.Value = true;
            app.upButton = uiradiobutton(app.ButtonGroup);
            app.upButton.Text = 'up';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Math(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end