classdef R_BG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RemovebackgroundPanel         matlab.ui.container.Panel
        StartEditFieldLabel           matlab.ui.control.Label
        StartEditField                matlab.ui.control.NumericEditField
        EndEditFieldLabel             matlab.ui.control.Label
        EndEditField                  matlab.ui.control.NumericEditField
        YesButton                     matlab.ui.control.Button
        SVDPanel                      matlab.ui.container.Panel
        YesButton_2                   matlab.ui.control.Button
        StartEditField_2Label         matlab.ui.control.Label
        StartEditField_2              matlab.ui.control.NumericEditField
        EndEditField_2Label           matlab.ui.control.Label
        EndEditField_2                matlab.ui.control.NumericEditField
        DynamicwindowbackgroundPanel  matlab.ui.container.Panel
        YesButton_3                   matlab.ui.control.Button
        WindowlengthLabel             matlab.ui.control.Label
        WindowlengthEditField         matlab.ui.control.NumericEditField
        StartpointEditFieldLabel      matlab.ui.control.Label
        StartpointEditField           matlab.ui.control.NumericEditField
        EndpointEditFieldLabel        matlab.ui.control.Label
        EndpointEditField             matlab.ui.control.NumericEditField
        RemovefilePanel               matlab.ui.container.Panel
        loadButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingapp % Description,主函数对应的属性
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, varargin)
            app.callingapp = mainapp;
        end

        % Button pushed function: YesButton
        function YesButtonPushed(app, event)
            if (app.StartEditField.Value>=1)&&(app.StartEditField.Value<=app.EndEditField.Value)
                Processing_Plus(app.callingapp,1,app.RemovebackgroundPanel.Title,floor(app.StartEditField.Value),floor(app.EndEditField.Value),0,0,0,0,0,0);
            else
                errordlg("Parameters false!",'error！')
                return
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingapp.R_backgroundMenu.Enable = 'on'; %%开启主app中的去背景步骤
            delete(app)
            
        end

        % Button pushed function: YesButton_2
        function YesButton_2Pushed(app, event)
            Processing_Plus(app.callingapp,1,app.SVDPanel.Title,app.StartEditField_2.Value,app.EndEditField_2.Value,0,0,0,0,0,0);
        end

        % Button pushed function: YesButton_3
        function YesButton_3Pushed(app, event)
            if app.WindowlengthEditField.Value>=3
                Processing_Plus(app.callingapp,1,app.DynamicwindowbackgroundPanel.Title,app.WindowlengthEditField.Value,app.StartpointEditField.Value,app.EndpointEditField.Value,0,0,0,0,0);
            else
                errordlg("Parameters false!",'error！')
                return
            end         
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            [filename,filepath] = uigetfile('*.*','Select file');
            Processing_Plus(app.callingapp,1,app.RemovefilePanel.Title,filepath,filename,0,0,0,0,0,0);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'face.jpg';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RemovebackgroundPanel = uipanel(app.UIFigure);
            app.RemovebackgroundPanel.Tooltip = {'选择背景道的范围。'};
            app.RemovebackgroundPanel.Title = 'Remove background';
            app.StartEditFieldLabel = uilabel(app.RemovebackgroundPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.RemovebackgroundPanel, 'numeric');
            app.StartEditField.Tooltip = {'输入大于等于1的整数'};
            app.StartEditField.Value = 1;
            app.EndEditFieldLabel = uilabel(app.RemovebackgroundPanel);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.RemovebackgroundPanel, 'numeric');
            app.EndEditField.Tooltip = {'大于等于start的整数，但是不要超过总道数（Columns）'};
            app.EndEditField.Value = 200;
            app.YesButton = uibutton(app.RemovebackgroundPanel, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton.Text = 'Yes';
            app.SVDPanel = uipanel(app.UIFigure);
            app.SVDPanel.Tooltip = {'选择保留的SVD模版范围。'};
            app.SVDPanel.Title = 'SVD';
            app.YesButton_2 = uibutton(app.SVDPanel, 'push');
            app.YesButton_2.ButtonPushedFcn = createCallbackFcn(app, @YesButton_2Pushed, true);
            app.YesButton_2.Text = 'Yes';
            app.StartEditField_2Label = uilabel(app.SVDPanel);
            app.StartEditField_2Label.Text = 'Start';
            app.StartEditField_2 = uieditfield(app.SVDPanel, 'numeric');
            app.StartEditField_2.Tooltip = {'请输入大于等于1的正整数'};
            app.StartEditField_2.Value = 2;
            app.EndEditField_2Label = uilabel(app.SVDPanel);
            app.EndEditField_2Label.Text = 'End';
            app.EndEditField_2 = uieditfield(app.SVDPanel, 'numeric');
            app.DynamicwindowbackgroundPanel = uipanel(app.UIFigure);
            app.DynamicwindowbackgroundPanel.Title = 'Dynamic window background';
            app.YesButton_3 = uibutton(app.DynamicwindowbackgroundPanel, 'push');
            app.YesButton_3.ButtonPushedFcn = createCallbackFcn(app, @YesButton_3Pushed, true);
            app.YesButton_3.Text = 'Yes';
            app.WindowlengthLabel = uilabel(app.DynamicwindowbackgroundPanel);
            app.WindowlengthLabel.Text = 'Window length';
            app.WindowlengthEditField = uieditfield(app.DynamicwindowbackgroundPanel, 'numeric');
            app.WindowlengthEditField.Tooltip = {'大于等于3的整数'};
            app.StartpointEditFieldLabel = uilabel(app.DynamicwindowbackgroundPanel);
            app.StartpointEditFieldLabel.Text = 'Start point';
            app.StartpointEditField = uieditfield(app.DynamicwindowbackgroundPanel, 'numeric');
            app.StartpointEditField.Tooltip = {'请输入大于等于1的正整数'};
            app.EndpointEditFieldLabel = uilabel(app.DynamicwindowbackgroundPanel);
            app.EndpointEditFieldLabel.Text = 'End point';
            app.EndpointEditField = uieditfield(app.DynamicwindowbackgroundPanel, 'numeric');
            app.EndpointEditField.Tooltip = {'请输入大于等于1，不超过纵向采样点数（Rows）的正整数'};
            app.RemovefilePanel = uipanel(app.UIFigure);
            app.RemovefilePanel.Tooltip = {'选择保留的SVD模版范围。'};
            app.RemovefilePanel.Title = 'Remove file';
            app.loadButton = uibutton(app.RemovefilePanel, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Tooltip = {'一个和输入数据一样大的矩阵'};
            app.loadButton.Text = 'load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = R_BG(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end