classdef Sar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SARPanel                      matlab.ui.container.Panel
        piontEditFieldLabel           matlab.ui.control.Label
        piontEditField                matlab.ui.control.NumericEditField
        alphaEditFieldLabel           matlab.ui.control.Label
        alphaEditField                matlab.ui.control.NumericEditField
        Permittivity_1EditFieldLabel  matlab.ui.control.Label
        Permittivity_1EditField       matlab.ui.control.NumericEditField
        Permittivity_2EditFieldLabel  matlab.ui.control.Label
        Permittivity_2EditField       matlab.ui.control.NumericEditField
        YesButton                     matlab.ui.control.Button
        FKmigrationPanel              matlab.ui.container.Panel
        PermittivityEditFieldLabel    matlab.ui.control.Label
        PermittivityEditField         matlab.ui.control.NumericEditField
        YesButton_2                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingapp % Description 保存主app  
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.callingapp = mainapp; %保存主app
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingapp.MigrationMenu.Enable = 'on';%开启主app二维滤波模块
            delete(app)
        end

        % Button pushed function: YesButton
        function YesButtonPushed(app, event)
            if (app.piontEditField.Value>=1)&&(0<=app.alphaEditField.Value&&app.alphaEditField.Value<=360)&&...
                    (1<=app.Permittivity_1EditField.Value&&app.Permittivity_1EditField.Value<=81)&&(1<=app.Permittivity_2EditField.Value&&app.Permittivity_2EditField.Value<=81)
                Processing_Plus(app.callingapp,1,app.SARPanel.Title,ceil(app.piontEditField.Value),app.alphaEditField.Value,app.Permittivity_1EditField.Value,app.Permittivity_2EditField.Value,0,0,0,0);
            else
                errordlg("Parameters false!",'error！')
                return
            end
        end

        % Button pushed function: YesButton_2
        function YesButton_2Pushed(app, event)
            if app.PermittivityEditField.Value >= 1
                Processing_Plus(app.callingapp,1,app.FKmigrationPanel.Title,app.PermittivityEditField.Value,0,0,0,0,0,0,0);
            else
                Processing_Plus(app.callingapp,1,app.FKmigrationPanel.Title,1,0,0,0,0,0,0,0);
                return
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'face.jpg';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SARPanel = uipanel(app.UIFigure);
            app.SARPanel.Tooltip = {'时域BP算法，双层目标，会存在边界效应'};
            app.SARPanel.Title = 'SAR';
            app.piontEditFieldLabel = uilabel(app.SARPanel);
            app.piontEditFieldLabel.Text = 'piont';
            app.piontEditField = uieditfield(app.SARPanel, 'numeric');
            app.piontEditField.Tooltip = {'纵向分界点，单层介质设为最大采样点数'};
            app.piontEditField.Value = 512;
            app.alphaEditFieldLabel = uilabel(app.SARPanel);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.SARPanel, 'numeric');
            app.alphaEditField.Tooltip = {'波束角（角度制）'};
            app.alphaEditField.Value = 60;
            app.Permittivity_1EditFieldLabel = uilabel(app.SARPanel);
            app.Permittivity_1EditFieldLabel.Text = 'Permittivity_1';
            app.Permittivity_1EditField = uieditfield(app.SARPanel, 'numeric');
            app.Permittivity_1EditField.Tooltip = {'第一层介质的相对介电常数[1-81]'};
            app.Permittivity_1EditField.Value = 1;
            app.Permittivity_2EditFieldLabel = uilabel(app.SARPanel);
            app.Permittivity_2EditFieldLabel.Text = 'Permittivity_2';
            app.Permittivity_2EditField = uieditfield(app.SARPanel, 'numeric');
            app.Permittivity_2EditField.Tooltip = {'第二层介质的相对介电常数[1-81]'};
            app.Permittivity_2EditField.Value = 9;
            app.YesButton = uibutton(app.SARPanel, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton.Text = 'Yes';
            app.FKmigrationPanel = uipanel(app.UIFigure);
            app.FKmigrationPanel.Tooltip = {'时域BP算法，双层目标，会存在边界效应'};
            app.FKmigrationPanel.Title = 'F-K migration';
            app.PermittivityEditFieldLabel = uilabel(app.FKmigrationPanel);
            app.PermittivityEditFieldLabel.Text = 'Permittivity';
            app.PermittivityEditField = uieditfield(app.FKmigrationPanel, 'numeric');
            app.PermittivityEditField.Tooltip = {'第一层介质的相对介电常数[1-81]'};
            app.PermittivityEditField.Value = 9;
            app.YesButton_2 = uibutton(app.FKmigrationPanel, 'push');
            app.YesButton_2.ButtonPushedFcn = createCallbackFcn(app, @YesButton_2Pushed, true);
            app.YesButton_2.Text = 'Yes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sar(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end