classdef TwoD_filter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        DfilterPanel              matlab.ui.container.Panel
        rowsEditFieldLabel        matlab.ui.control.Label
        rowsEditField             matlab.ui.control.NumericEditField
        columnsEditFieldLabel     matlab.ui.control.Label
        columnsEditField          matlab.ui.control.NumericEditField
        YesButton                 matlab.ui.control.StateButton
        ButtonGroup               matlab.ui.container.ButtonGroup
        meanfilterButton          matlab.ui.control.RadioButton
        medianfilterButton        matlab.ui.control.RadioButton
        f_kfilterPanel            matlab.ui.container.Panel
        LgmEditFieldLabel         matlab.ui.control.Label
        LgmEditField              matlab.ui.control.NumericEditField
        LsmEditFieldLabel         matlab.ui.control.Label
        LsmEditField              matlab.ui.control.NumericEditField
        YesButton_2               matlab.ui.control.StateButton
        EMDPanel                  matlab.ui.container.Panel
        EndimfEditFieldLabel      matlab.ui.control.Label
        EndimfEditField           matlab.ui.control.NumericEditField
        StartimfEditFieldLabel    matlab.ui.control.Label
        StartimfEditField         matlab.ui.control.NumericEditField
        YesButton_3               matlab.ui.control.StateButton
        ButtonGroup_2             matlab.ui.container.ButtonGroup
        verticalButton            matlab.ui.control.RadioButton
        HorizontalButton          matlab.ui.control.RadioButton
        VMDPanel                  matlab.ui.container.Panel
        EndimfEditField_2Label    matlab.ui.control.Label
        EndimfEditField_2         matlab.ui.control.NumericEditField
        YesButton_4               matlab.ui.control.StateButton
        ButtonGroup_3             matlab.ui.container.ButtonGroup
        verticalButton_2          matlab.ui.control.RadioButton
        HorizontalButton_2        matlab.ui.control.RadioButton
        StartimfEditField_2Label  matlab.ui.control.Label
        StartimfEditField_2       matlab.ui.control.NumericEditField
        NumimfEditFieldLabel      matlab.ui.control.Label
        NumimfEditField           matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        callingapp % Description 保存主app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.callingapp = mainapp; %保存主app
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingapp.TwoDfilterMenu.Enable = 'on';%开启主app二维滤波模块
            delete(app)
        end

        % Value changed function: YesButton
        function YesButtonValueChanged(app, event)
            if app.medianfilterButton.Value
                Processing_Plus(app.callingapp,1,app.DfilterPanel.Title,app.medianfilterButton.Text,app.rowsEditField.Value,app.columnsEditField.Value,0,0,0,0,0);
            elseif app.meanfilterButton.Value
                Processing_Plus(app.callingapp,1,app.DfilterPanel.Title,app.meanfilterButton.Text,app.rowsEditField.Value,app.columnsEditField.Value,0,0,0,0,0);
            end
        end

        % Value changed function: YesButton_2
        function YesButton_2ValueChanged(app, event)
            Processing_Plus(app.callingapp,1,app.f_kfilterPanel.Title,app.LgmEditField.Value,app.LsmEditField.Value,0,0,0,0,0,0);
        end

        % Value changed function: YesButton_3
        function YesButton_3ValueChanged(app, event)
            if app.HorizontalButton.Value
                Processing_Plus(app.callingapp,1,app.EMDPanel.Title,app.StartimfEditField.Value,app.EndimfEditField.Value,0,0,0,0,0,0);
            else
                Processing_Plus(app.callingapp,1,app.EMDPanel.Title,app.StartimfEditField.Value,app.EndimfEditField.Value,1,0,0,0,0,0);
            end
        end

        % Value changed function: YesButton_4
        function YesButton_4ValueChanged(app, event)
            if app.HorizontalButton_2.Value
                Processing_Plus(app.callingapp,1,app.VMDPanel.Title,app.StartimfEditField_2.Value,app.EndimfEditField_2.Value,app.NumimfEditField.Value,0,0,0,0,0);
            else
                Processing_Plus(app.callingapp,1,app.VMDPanel.Title,app.StartimfEditField_2.Value,app.EndimfEditField_2.Value,app.NumimfEditField.Value,1,0,0,0,0);
            end      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'face.jpg';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DfilterPanel = uipanel(app.UIFigure);
            app.DfilterPanel.Tooltip = {'2维滤波，常用于去除高频噪声，平滑整个图像。由于要采用模板进行运算，对于雷达数据来说，效率较低。'};
            app.DfilterPanel.Title = '2D filter';
            app.rowsEditFieldLabel = uilabel(app.DfilterPanel);
            app.rowsEditFieldLabel.Text = 'rows';
            app.rowsEditField = uieditfield(app.DfilterPanel, 'numeric');
            app.rowsEditField.Tooltip = {'滤波模板的行，大于等于3，越大执行时间越久。'};
            app.rowsEditField.Value = 5;
            app.columnsEditFieldLabel = uilabel(app.DfilterPanel);
            app.columnsEditFieldLabel.Text = 'columns';
            app.columnsEditField = uieditfield(app.DfilterPanel, 'numeric');
            app.columnsEditField.Tooltip = {'滤波模板的列，大于等于3，越大执行越久。'};
            app.columnsEditField.Value = 5;
            app.YesButton = uibutton(app.DfilterPanel, 'state');
            app.YesButton.ValueChangedFcn = createCallbackFcn(app, @YesButtonValueChanged, true);
            app.YesButton.Text = 'Yes';
            app.ButtonGroup = uibuttongroup(app.DfilterPanel);
            app.meanfilterButton = uiradiobutton(app.ButtonGroup);
            app.meanfilterButton.Text = 'mean filter';
            app.medianfilterButton = uiradiobutton(app.ButtonGroup);
            app.medianfilterButton.Text = 'median filter';
            app.medianfilterButton.Value = true;
            app.f_kfilterPanel = uipanel(app.UIFigure);
            app.f_kfilterPanel.Tooltip = {'2维滤波，一半用于去除高频噪声，平滑整个图像。由于要采用模板进行运算，对于雷达数据来说，效率较低。'};
            app.f_kfilterPanel.Title = 'f_k filter';
            app.LgmEditFieldLabel = uilabel(app.f_kfilterPanel);
            app.LgmEditFieldLabel.Text = 'Lg(m)';
            app.LgmEditField = uieditfield(app.f_kfilterPanel, 'numeric');
            app.LgmEditField.Tooltip = {'低频扰动窗口长度'};
            app.LgmEditField.Value = 40;
            app.LsmEditFieldLabel = uilabel(app.f_kfilterPanel);
            app.LsmEditFieldLabel.Text = 'Ls(m)';
            app.LsmEditField = uieditfield(app.f_kfilterPanel, 'numeric');
            app.LsmEditField.Tooltip = {'设备微抖动最大位移'};
            app.LsmEditField.Value = 0.2;
            app.YesButton_2 = uibutton(app.f_kfilterPanel, 'state');
            app.YesButton_2.ValueChangedFcn = createCallbackFcn(app, @YesButton_2ValueChanged, true);
            app.YesButton_2.Text = 'Yes';
            app.EMDPanel = uipanel(app.UIFigure);
            app.EMDPanel.Tooltip = {'2维滤波，一半用于去除高频噪声，平滑整个图像。由于要采用模板进行运算，对于雷达数据来说，效率较低。'};
            app.EMDPanel.Title = 'EMD';
            app.EndimfEditFieldLabel = uilabel(app.EMDPanel);
            app.EndimfEditFieldLabel.Text = '-End(imf)';
            app.EndimfEditField = uieditfield(app.EMDPanel, 'numeric');
            app.EndimfEditField.Limits = [0 10];
            app.EndimfEditField.ValueDisplayFormat = '%.0f';
            app.EndimfEditField.Tooltip = {'低频扰动窗口长度'};
            app.EndimfEditField.Value = 2;
            app.StartimfEditFieldLabel = uilabel(app.EMDPanel);
            app.StartimfEditFieldLabel.Text = 'Start(imf)';
            app.StartimfEditField = uieditfield(app.EMDPanel, 'numeric');
            app.StartimfEditField.ValueDisplayFormat = '%.0f';
            app.StartimfEditField.Tooltip = {'设备微抖动最大位移'};
            app.StartimfEditField.Value = 1;
            app.YesButton_3 = uibutton(app.EMDPanel, 'state');
            app.YesButton_3.ValueChangedFcn = createCallbackFcn(app, @YesButton_3ValueChanged, true);
            app.YesButton_3.Text = 'Yes';
            app.ButtonGroup_2 = uibuttongroup(app.EMDPanel);
            app.verticalButton = uiradiobutton(app.ButtonGroup_2);
            app.verticalButton.Text = 'vertical';
            app.HorizontalButton = uiradiobutton(app.ButtonGroup_2);
            app.HorizontalButton.Text = 'Horizontal';
            app.HorizontalButton.Value = true;
            app.VMDPanel = uipanel(app.UIFigure);
            app.VMDPanel.Tooltip = {'2维滤波，一半用于去除高频噪声，平滑整个图像。由于要采用模板进行运算，对于雷达数据来说，效率较低。'};
            app.VMDPanel.Title = 'VMD';
            app.EndimfEditField_2Label = uilabel(app.VMDPanel);
            app.EndimfEditField_2Label.Text = 'End(imf)';
            app.EndimfEditField_2 = uieditfield(app.VMDPanel, 'numeric');
            app.EndimfEditField_2.Limits = [3 8];
            app.EndimfEditField_2.ValueDisplayFormat = '%.0f';
            app.EndimfEditField_2.Tooltip = {'设备微抖动最大位移'};
            app.EndimfEditField_2.Value = 4;
            app.YesButton_4 = uibutton(app.VMDPanel, 'state');
            app.YesButton_4.ValueChangedFcn = createCallbackFcn(app, @YesButton_4ValueChanged, true);
            app.YesButton_4.Text = 'Yes';
            app.ButtonGroup_3 = uibuttongroup(app.VMDPanel);
            app.verticalButton_2 = uiradiobutton(app.ButtonGroup_3);
            app.verticalButton_2.Text = 'vertical';
            app.HorizontalButton_2 = uiradiobutton(app.ButtonGroup_3);
            app.HorizontalButton_2.Text = 'Horizontal';
            app.HorizontalButton_2.Value = true;
            app.StartimfEditField_2Label = uilabel(app.VMDPanel);
            app.StartimfEditField_2Label.Text = 'Start(imf)';
            app.StartimfEditField_2 = uieditfield(app.VMDPanel, 'numeric');
            app.StartimfEditField_2.Limits = [1 7];
            app.StartimfEditField_2.ValueDisplayFormat = '%.0f';
            app.StartimfEditField_2.Tooltip = {'设备微抖动最大位移'};
            app.StartimfEditField_2.Value = 1;
            app.NumimfEditFieldLabel = uilabel(app.VMDPanel);
            app.NumimfEditFieldLabel.Text = 'Num(imf)';
            app.NumimfEditField = uieditfield(app.VMDPanel, 'numeric');
            app.NumimfEditField.Limits = [3 10];
            app.NumimfEditField.ValueDisplayFormat = '%.0f';
            app.NumimfEditField.Tooltip = {'低频扰动窗口长度'};
            app.NumimfEditField.Value = 5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TwoD_filter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end