classdef Wavelet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        OneDdwtPanel              matlab.ui.container.Panel
        YesButton                 matlab.ui.control.Button
        TwoDdwtPanel              matlab.ui.container.Panel
        YesButton_2               matlab.ui.control.Button
        OneDcwtPanel              matlab.ui.container.Panel
        YesButton_3               matlab.ui.control.Button
        LFMHzEditFieldLabel       matlab.ui.control.Label
        LFMHzEditField            matlab.ui.control.NumericEditField
        LTnsEditFieldLabel        matlab.ui.control.Label
        LTnsEditField             matlab.ui.control.NumericEditField
        StarttrackEditFieldLabel  matlab.ui.control.Label
        StarttrackEditField       matlab.ui.control.NumericEditField
        HFMHzEditFieldLabel       matlab.ui.control.Label
        HFMHzEditField            matlab.ui.control.NumericEditField
        HTnsEditFieldLabel        matlab.ui.control.Label
        HTnsEditField             matlab.ui.control.NumericEditField
        EndtrackEditFieldLabel    matlab.ui.control.Label
        EndtrackEditField         matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        callingapp % Description,主函数对应的属性，示例子-1
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, varargin)
            app.callingapp = mainapp;%定义主函数，示例子-2
        end

        % Button pushed function: YesButton
        function YesButtonPushed(app, event)
           
            Processing_Plus(app.callingapp,1,app.OneDdwtPanel.Title,0,0,0,0,0,0,0,0);%具体算法步骤，传回10个参数，示例子-3-1
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.callingapp.WaveletMenu.Enable = 'on'; %%开启主app中的去背景步骤，示例子-4
            delete(app)
            
        end

        % Button pushed function: YesButton_2
        function YesButton_2Pushed(app, event)
            Processing_Plus(app.callingapp,1,app.TwoDdwtPanel.Title,0,0,0,0,0,0,0,0);%具体算法步骤，传回10个参数，示例子-3-2
        end

        % Button pushed function: YesButton_3
        function YesButton_3Pushed(app, event)
            if (app.LFMHzEditField.Value>=0)&&(app.HTnsEditField.Value>=0)
                Processing_Plus(app.callingapp,1,app.OneDcwtPanel.Title,app.LFMHzEditField.Value,app.HFMHzEditField.Value,app.LTnsEditField.Value,app.HTnsEditField.Value,app.StarttrackEditField.Value,app.EndtrackEditField.Value,0,0);
            else
                errordlg("Parameters false!",'error！')
                return
            end         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'face.jpg';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OneDdwtPanel = uipanel(app.UIFigure);
            app.OneDdwtPanel.Title = 'OneD-dwt';
            app.YesButton = uibutton(app.OneDdwtPanel, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton.Text = 'Yes';
            app.TwoDdwtPanel = uipanel(app.UIFigure);
            app.TwoDdwtPanel.Title = 'TwoD-dwt';
            app.YesButton_2 = uibutton(app.TwoDdwtPanel, 'push');
            app.YesButton_2.ButtonPushedFcn = createCallbackFcn(app, @YesButton_2Pushed, true);
            app.YesButton_2.Text = 'Yes';
            app.OneDcwtPanel = uipanel(app.UIFigure);
            app.OneDcwtPanel.Tooltip = {'连续小波滤波，在时频域中选择作用的频率，时窗。也可以选择作用数据的范围。（使用该方法之前，请确保明白时频分析的物理意义）'};
            app.OneDcwtPanel.Title = 'OneD-cwt';
            app.YesButton_3 = uibutton(app.OneDcwtPanel, 'push');
            app.YesButton_3.ButtonPushedFcn = createCallbackFcn(app, @YesButton_3Pushed, true);
            app.YesButton_3.Text = 'Yes';
            app.LFMHzEditFieldLabel = uilabel(app.OneDcwtPanel);
            app.LFMHzEditFieldLabel.Text = 'LF（MHz）';
            app.LFMHzEditField = uieditfield(app.OneDcwtPanel, 'numeric');
            app.LFMHzEditField.Tooltip = {'大于等于3的整数'};
            app.LTnsEditFieldLabel = uilabel(app.OneDcwtPanel);
            app.LTnsEditFieldLabel.Text = 'LT（ns）';
            app.LTnsEditField = uieditfield(app.OneDcwtPanel, 'numeric');
            app.LTnsEditField.Tooltip = {'请输入大于等于1的正整数'};
            app.StarttrackEditFieldLabel = uilabel(app.OneDcwtPanel);
            app.StarttrackEditFieldLabel.Text = 'Start track';
            app.StarttrackEditField = uieditfield(app.OneDcwtPanel, 'numeric');
            app.StarttrackEditField.Tooltip = {'请输入大于等于1的正整数'};
            app.HFMHzEditFieldLabel = uilabel(app.OneDcwtPanel);
            app.HFMHzEditFieldLabel.Text = 'HF（MHz）';
            app.HFMHzEditField = uieditfield(app.OneDcwtPanel, 'numeric');
            app.HFMHzEditField.Tooltip = {'低于采样频率的一半'};
            app.HTnsEditFieldLabel = uilabel(app.OneDcwtPanel);
            app.HTnsEditFieldLabel.Text = 'HT（ns）';
            app.HTnsEditField = uieditfield(app.OneDcwtPanel, 'numeric');
            app.HTnsEditField.Tooltip = {'小于等于时窗长度'};
            app.EndtrackEditFieldLabel = uilabel(app.OneDcwtPanel);
            app.EndtrackEditFieldLabel.Text = 'End track';
            app.EndtrackEditField = uieditfield(app.OneDcwtPanel, 'numeric');
            app.EndtrackEditField.Tooltip = {'不要超过总道数'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Wavelet(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end